classdef SR300_App_vAlpha_v71 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
    end

%This program shows options for paramters with the SR300
%requires optionstable.mat
    properties (Access = public)
        TabGroup                     matlab.ui.container.TabGroup
        allTabs                      matlab.ui.container.Tab
        paramListBox                 matlab.ui.control.ListBox
        setselectiontodefaultButton  matlab.ui.control.Button
        setallparameterstodefaultvaluesButton  matlab.ui.control.Button
        allstreamButton              matlab.ui.control.StateButton
        colorstreamButton            matlab.ui.control.StateButton
        %colorstreamButton            matlab.ui.control.Button
        pointcloudButton             matlab.ui.control.StateButton
        depthstreamButton            matlab.ui.control.StateButton
        colorstreamButtonFlag %0 or 1
        depthstreamButtonFlag %0 or 1
        allstreamButtonFlag   %0 or 1
        pointcloudButtonFlag  %0 or 1
        %color calibration propert.
        colorcalibrationButton       matlab.ui.control.Button
        figcalorig
        axcalorig
        figcalcorr
        axcalcorr
        %Ivcam objects
        ivcampresetDropDownLabel     matlab.ui.control.Label
        ivcampresetDropDown          matlab.ui.control.DropDown
        %variables for loading ivcam presets
        ivcamopts
        ivcamoptvals
        ivcampresets
        ivcamPanel                  matlab.ui.container.Panel
        minMaxDefPanel              matlab.ui.container.Panel  
        %slider creation
        SliderMinMaxText           matlab.ui.control.Label
        SliderMinValue             matlab.ui.control.Label
        SliderMaxValue             matlab.ui.control.Label
        SliderText                 matlab.ui.control.Label
        SliderLabel                matlab.ui.control.Label
        SliderITSELF               matlab.ui.control.Slider
        ValueSpinnerLabel          matlab.ui.control.Label
        ValueSpinner               matlab.ui.control.Spinner
        defaultValueBoxLabel       matlab.ui.control.Label
        defaultValueBox            matlab.ui.control.Label
        CameraDropDownLabel        matlab.ui.control.Label
        CameraDropDown             matlab.ui.control.DropDown
        descriptionTextArea          matlab.ui.control.TextArea
        descriptionTextAreaLabel     matlab.ui.control.Label
        stepsDisplayed % for saving array of 11 elements to be displayed on slider
        optionsTitlesCamera %titles, descriptions, and parameters
        
        %struct with options for connected cameras. camera field SR300_1 F200_1 F200_2 etc is a cell array with all options
        %syntax: app.selCamOptTable.SR300_1 , SR300_1 being the first camera connected (matches names on
        selCamOptTable
        presentCamSelecOptTable %friendlier way of selecting parameters on the table
        
        %num array for defaul, min, and max values
        minMaxDefaultStepValues %increases increment/decrement step to 10
        connctdShortCamNames % all connnected cameras - cell array ex: {'SR300' 'F200'}     
        %camera %name of the camera
        disFromBottomMinMaxBox %distance from botton of min max box
        tabSelection %selected tab Object; app.tabSelection.Title for string with title
        index
        functionCall %for saving the paramter/function's name
        deviceFriendlyName
%%%%%%% C++ library variables/objects %%%%%%%%%%%%%%%%%%
        dev %pointer - camera
        opt %from 0 to 68 (corresponds to functions' names
        dev_opt_current_value %t
        %SliderTitleBottomPosition %height of slider title from the bottom
        listBoxPosition
        rsoptions
        width
        height
        fps
        ctx
        rsEnum
        err
        depth_intrin
        color_intrin
        depth_to_color
        methodinfo
        structs
        ThunkLibName
        scale
        ardobj
        %variables for selecting camera
        devIdx
        deviceCount
        selectdev %index of selected camera
        %timer variables
        timercolor
        timerdepth
        timerpcl
        timerall
        %axes and figure handles
        figcolor
        figdepth
        figpcl
        figall
        axcolor
        axdepth
        axpcl
        axall
        %stream snapshot counts
        countcolor
        countdepth
        countpcl
    end

    methods (Access = private)
%>>>>>>>>> GetOptions >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        function GetOptions(app)
            %
            [app.dev_opt_current_value, ~, app.err] = calllib('realsense', 'rs_get_device_option', app.dev{app.selectdev}, app.opt, rs_error);
            rs_check_error(app.err);
            app.ValueSpinner.Value = app.dev_opt_current_value;
            app.SliderITSELF.Value = app.ValueSpinner.Value;
         end
%>>>>>>>>>> END of GetOptioons

%>>>>>>>>> buildivcamPanel >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        function buildivcamPanel(app)
            app.ivcamPanel = uipanel(app.tabSelection);
            app.ivcamPanel.FontWeight = 'bold';
            app.ivcamPanel.Title = 'Ivcam presets'
            %titleIvcam = app.presentCamSelecOptTable{app.index,3}
            %distFromBottom = app.tabSelection.Position(-50; app.listBoxPosition(2)-250; %relative to list box and tab bottom
            widthIvcam = app.TabGroup.SelectedTab.Position(3)-100;
            app.ivcamPanel.Position = [app.listBoxPosition(1) 0 widthIvcam 230];
            buildIvcamItem(app);
            
             %attempt to print out all parameteres from ivcam       
% for i=1:(length(app.ivcampresets(:,1))-1)
%             app.ivcamShowPresetLabel(i) = uilabel(app.ivcamPanel);
%             app.ivcamShowPresetLabel(i).HorizontalAlignment = 'right';
%             app.ivcamShowPresetLabel(i).FontWeight = 'bold';
%             app.ivcamShowPresetLabel(i).Position = [5+j 70 100 15];
%             app.ivcamShowPresetLabel(i).Text = { app.ivcampresets{1,i}; '';''; app.ivcampresetDropDown.Items{1,i+1}};
% 	j = j + 
% 
% end
        end
%>>>>>>>>>> END of buildivcamPanel

%>>>>>>>>> buildBottomButtons >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        function buildBottomButtons(app)
             % Create setallparameterstodefaultvaluesButton
            %app.bottombuttonsPosition = [3 app.UIFigure.Position(2) app.UIFigure.Position(3) app.UIFigure.Position(4)]
            
            distFromBottom = 30;
            buttonsHeight=22;
            distBetweenButtons = 15;
            
            app.setallparameterstodefaultvaluesButton = uibutton(app.UIFigure, 'push');
            app.setallparameterstodefaultvaluesButton.ButtonPushedFcn = createCallbackFcn(app, @setallparameterstodefaultvaluesButtonPushed, true);
            app.setallparameterstodefaultvaluesButton.FontColor = [1 0 0];
            app.setallparameterstodefaultvaluesButton.Position = [17.5 distFromBottom 150 buttonsHeight];
            app.setallparameterstodefaultvaluesButton.Text = 'default for all parameters';
            
            % Create colorcalibrationButton
            previousButPos = app.setallparameterstodefaultvaluesButton.Position;
            presentButLeftPos = previousButPos(1) + previousButPos(3) + distBetweenButtons;
            app.colorcalibrationButton = uibutton(app.UIFigure, 'push');
            app.colorcalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @colorcalibrationButtonPushed, true);
            app.colorcalibrationButton.Position = [presentButLeftPos distFromBottom 100 buttonsHeight];
            app.colorcalibrationButton.Text = 'color calibration';
            %clear previousButPos;

            % Create colorstreamButton
            previousButPos = app.colorcalibrationButton.Position;
            presentButLeftPos = previousButPos(1) + previousButPos(3) + distBetweenButtons;
            app.colorstreamButton = uibutton(app.UIFigure, 'state');
            %app.colorstreamButton = uibutton(app.UIFigure, 'push');
            app.colorstreamButton.ValueChangedFcn = createCallbackFcn(app, @colorstreamButtonValueChanged, true);
            %app.colorstreamButton.ButtonPushedFcn = createCallbackFcn(app, @colorstreamButtonValueChanged, true);
            app.colorstreamButton.Text = 'color stream';
            app.colorstreamButton.Position = [presentButLeftPos distFromBottom  80 buttonsHeight];
            
            % Create depthstreamButton
            previousButPos = app.colorstreamButton.Position;
            presentButLeftPos = previousButPos(1) + previousButPos(3) + distBetweenButtons;
            app.depthstreamButton = uibutton(app.UIFigure, 'state');
            app.depthstreamButton.ValueChangedFcn = createCallbackFcn(app, @depthstreamButtonValueChanged, true);
            app.depthstreamButton.Text = 'depth stream';
            app.depthstreamButton.Position = [presentButLeftPos distFromBottom  80 buttonsHeight];
            
            % Create allstreamButton
            previousButPos = app.depthstreamButton.Position;
            presentButLeftPos = previousButPos(1) + previousButPos(3) + distBetweenButtons;
            app.allstreamButton = uibutton(app.UIFigure, 'state');
            app.allstreamButton.ValueChangedFcn = createCallbackFcn(app, @allstreamButtonValueChanged, true);
            app.allstreamButton.Text = 'all stream';
            app.allstreamButton.Position = [presentButLeftPos distFromBottom  80 buttonsHeight];

            % Create pointcloudButton
            previousButPos = app.allstreamButton.Position;
            presentButLeftPos = previousButPos(1) + previousButPos(3) + distBetweenButtons;
            app.pointcloudButton = uibutton(app.UIFigure, 'state');
            app.pointcloudButton.ValueChangedFcn = createCallbackFcn(app, @pointcloudButtonValueChanged, true);
            app.pointcloudButton.Text = 'point cloud';
            app.pointcloudButton.Position = [presentButLeftPos distFromBottom  80 buttonsHeight];

        end
%>>>>>>>>>> END of buildBottomButtons

%>>>>>>>>>>  buildSelection >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        % app required parameter
        % minMax is a 2 element horizontal integers vector 
                % 1st element = min 
                % 2nd = MAX values for parameter
        % description is a cell-array with comments about the parameter
            %the cell-array has syntax {'blah blah blah';'blah blah blah'} - the ; (semicolon) for newline
        % discreteOrNot can be any number  for discrete slider or 0 for nondiscrete
        % stepsDisplayed is a function that generates a vector with up to 10 elements
        % WORKS FOR PARAMATERS WITH STEP OF 1 (positive integer)
        
%        function buildSelection(app, title, index)
        function buildSelection(app)
            erasePreviousSelection(app); % clears previous slider and number boxes
            %creates array of doubles min, max, default, and step parameter values from cell array
            app.minMaxDefaultStepValues = cell2mat([app.presentCamSelecOptTable(app.index,7);...
                app.presentCamSelecOptTable(app.index,9); app.presentCamSelecOptTable(app.index,8);...
                app.presentCamSelecOptTable(app.index,10)]);
            getValuesDispBySlider(app); %produces array with values to be shown by slider
            buildMinMaxBoxes(app);%shows boxes with min and max possible values
            createParameterSlider(app); %shows new slider
            createSpinner(app); % shows pinner
            createDescription(app);
            GetOptions(app);%interfaces with hardware
        end
%>>>>>>>>>>  END of buildSelection

%>>>>>>>>>>  erasePreviousSelection >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        function erasePreviousSelection(app) %clears previous selection
            delete(app.minMaxDefPanel);
            delete(app.SliderMinValue);
            delete(app.SliderMinMaxText);
            delete(app.SliderMaxValue);
            delete(app.SliderText);
            delete(app.SliderLabel);
            delete(app.SliderITSELF);
            %delete(app.ValueSpinnerLabel);
            %delete(app.ValueSpinner);
            delete(app.descriptionTextArea);
            delete(app.descriptionTextAreaLabel);
            clear app.minMaxDefaultStepValues;
            clear app.stepsDisplayed;
        end
%>>>>>>>>>>  END of erasePreviousSelection

%>>>>>>>>>>  stepsDisplayed >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
%calcultes which and how many steps the slider has
function getValuesDispBySlider(app)
            rangeBetweenMinMax = abs(app.minMaxDefaultStepValues(2)-app.minMaxDefaultStepValues(1));
            
            if rangeBetweenMinMax >10  % if abs(max - min) > 10, defines 10 steps
                step = (app.minMaxDefaultStepValues(2)-app.minMaxDefaultStepValues(1))/10;
                app.stepsDisplayed = zeros(1,11);
                app.stepsDisplayed(1) = app.minMaxDefaultStepValues(1); %initializes smallest value
                app.stepsDisplayed(11) = app.minMaxDefaultStepValues(2); %initializes smallest value
                for i = 2:10 %initializes slider min < (slider values) < max 
                   app.stepsDisplayed(i) = round(step + app.stepsDisplayed(i-1));
                end
                app.stepsDisplayed(6) = app.minMaxDefaultStepValues(3);
                %app.dev_opt_current_value;%center of slide = current value (not necessarily shown)
            else
                app.stepsDisplayed = zeros(1, rangeBetweenMinMax +1);%+1 to make sure the max value will be shown in arrays with 0 values
                app.stepsDisplayed(1) = app.minMaxDefaultStepValues(1);
                app.stepsDisplayed(rangeBetweenMinMax+1) = app.minMaxDefaultStepValues(2); % rangeBetweenMinMax alsp accounts max = 0 adn min < 0      
                for i = 2:rangeBetweenMinMax %initializes slider min < (slider values) < max 
                   app.stepsDisplayed(i) = app.stepsDisplayed(i-1)+1;
                end
            end
        end
%>>>>>>>>>>  END of getValuesDispBySlider

%>>>>>>> buildIvcamItem >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        function buildIvcamItem(app)

            % Create ivcampresetDropDown
            app.ivcampresetDropDown = uidropdown(app.ivcamPanel);
            app.ivcampresetDropDown.Items = {'ShortRange', 'LongRange', 'BackgroundSegmentation', 'GestureRecognition', 'ObjectScanning', 'FaceAnalytics', 'FaceLogin', 'GRCursor', 'Default', 'MidRange', 'IROnly'};
            app.ivcampresetDropDown.ValueChangedFcn = createCallbackFcn(app, @ivcampresetDropDownValueChanged, true);
            app.ivcampresetDropDown.Position = [20 170 200 25];
            app.ivcampresetDropDown.Value = 'ShortRange';
        end

%>>>>>>> buildTabs >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        function buildTabs(app)
            for i = 1:app.deviceCount
                app.allTabs(i) = uitab(app.TabGroup);
                app.allTabs(i).AutoResizeChildren = 'off';
                app.allTabs(i).Title = strcat(strcat...
                    (app.connctdShortCamNames(i),strcat('_',string(i)))); %ex: 'SR300_1' tab name, ...
                                                            %...which matches struc app.selCamOptTable.SR300_1 where
                                                            %data is held
            end
            buildListBox(app);
        end
%>>>>> END of buildTabs

%>>>>>>> buildListBox >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        function buildListBox(app)
            buildCompleteParamList(app); %script for getting all parameters
            for i = 1:app.deviceCount
                app.paramListBox(i) = uilistbox(app.allTabs(i));
                app.paramListBox(i).Items = getParListItems(app,i);
                app.paramListBox(i).ValueChangedFcn = ...
                createCallbackFcn(app, @selectedParameter, true);
        	app.paramListBox(i).Position = [20 250 296 (18.2*length(app.paramListBox(i).Items))];
                app.paramListBox(i).Value = {};
            end
            
            for i=1:app.deviceCount
                app.paramListBox(i).Value = app.paramListBox(i).Items{1};
            end
            app.listBoxPosition = app.paramListBox(1).Position;%[sizes]
        end
%>>>>> END of buildListBox

%>>>>>>>>>> optionTitleReturned >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
    function optionList = getParListItems(app,loopIndex)
        optionTitleReturned = cell(app.selCamOptTable.(app.allTabs(loopIndex).Title)(:,:));
        optionTitleReturned = app.selCamOptTable.(app.allTabs(loopIndex).Title)(1,4);%saves the first item

        for i=2:length(app.selCamOptTable.(app.allTabs(loopIndex).Title)(:,4))%first description has been saved alaready
            strFindCondition = app.selCamOptTable.(app.allTabs(loopIndex).Title){i,4};
            %the following options should be exposed properly out of the listbox
            NOTautoRange = ~contains(strFindCondition, 'auto range');
            NOTframe = ~contains(strFindCondition, 'frame');
            NOTlogger = ~contains(strFindCondition, 'logger');
            NOTfps = ~contains(strFindCondition, 'fps');
            if NOTautoRange && NOTframe && NOTlogger && NOTfps %checks for ivcam presets & slider-unsupported ones
                optionTitleReturned = [optionTitleReturned;app.selCamOptTable.(app.allTabs(loopIndex).Title)(i,4)];
            end
        end
        optionList = optionTitleReturned; 
    end
%>>>>>>>>> % END of optionTitleReturned

%>>>>>>> createDescription >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        function createDescription(app)
            app.descriptionTextArea = uitextarea(app.minMaxDefPanel);
            app.descriptionTextArea.Editable = 'off';
            app.descriptionTextArea.BackgroundColor = [0.9412 0.9412 0.9412];
            app.descriptionTextArea.Position = [5 5 228.015625 90];
            app.descriptionTextArea.FontWeight = 'normal';
            app.descriptionTextArea.Value = app.presentCamSelecOptTable(app.index,5);
            
            %creates description label
%             descrptPos = app.descriptionTextArea.Position;
%             app.descriptionTextAreaLabel = uilabel(app.minMaxDefPanel);
%             app.descriptionTextAreaLabel.Position = [(5+descrptPos(1)) (48+descrptPos(2)) 80 30];
%             app.descriptionTextAreaLabel.HorizontalAlignment = 'right';
%             app.descriptionTextAreaLabel.Text = 'description';
        end
%>>>>> END of createDescription

%>>>>>>>>> buildivcamPanel >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        function buildMinMaxDefPanel(app)
            app.minMaxDefPanel = uipanel(app.tabSelection);
            %app.minMaxDefPanel.FontWeight = 'bold';
            %app.minMaxDefPanel.Title = 'Ivcam presets'
            distFromLeft = app.listBoxPosition(1)+app.listBoxPosition(3)+20;%distfrom left + width of list box
            app.minMaxDefPanel.Position = [distFromLeft app.listBoxPosition(2) 240 app.listBoxPosition(4)];
        end
%>>>>>>>>>> END of buildivcamPanel

%>>>>>>>>> showMinMaxBoxes >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        function buildMinMaxBoxes(app)
            buildMinMaxDefPanel(app);
            app.disFromBottomMinMaxBox = app.minMaxDefPanel.Position(4)-50;
            % Create SliderMinMaxText
            app.SliderMinMaxText = uilabel(app.minMaxDefPanel);
            app.SliderMinMaxText.Position = [5 app.disFromBottomMinMaxBox 28 42];
            app.SliderMinMaxText.Text = {'max'; ''; 'min'};
            
            % for MAX
            app.SliderMaxValue = uilabel(app.minMaxDefPanel);
            app.SliderMaxValue.BackgroundColor = [0.8588 0.9451 0.9882];
            app.SliderMaxValue.HorizontalAlignment = 'right';
            app.SliderMaxValue.FontWeight = 'bold';
            app.SliderMaxValue.Position = [37 (app.disFromBottomMinMaxBox+27) 42 15];
            app.SliderMaxValue.Text = num2str(app.minMaxDefaultStepValues(2));

            % MIN
            app.SliderMinValue = uilabel(app.minMaxDefPanel);
            app.SliderMinValue.BackgroundColor = [0.8588 0.9451 0.9882];
            app.SliderMinValue.HorizontalAlignment = 'right';
            app.SliderMinValue.FontWeight = 'bold';
            app.SliderMinValue.Position = [37 (app.disFromBottomMinMaxBox-2) 42 15];
            app.SliderMinValue.Text = num2str(app.minMaxDefaultStepValues(1));
            
            % Create defaultValueBoxLabel
            app.defaultValueBoxLabel = uilabel(app.minMaxDefPanel);
            app.defaultValueBoxLabel.Position = [102 app.disFromBottomMinMaxBox 50 42];
            app.defaultValueBoxLabel.FontWeight = 'normal';
            app.defaultValueBoxLabel.HorizontalAlignment = 'center';
            app.defaultValueBoxLabel.Text = {'default'};
            
            % Create defaultValueBox - box at the slider's left-hand side
            app.defaultValueBox = uilabel(app.minMaxDefPanel);
            app.defaultValueBox.BackgroundColor = [0.8588 0.9451 0.9882];
            app.defaultValueBox.HorizontalAlignment = 'right';
            app.defaultValueBox.FontWeight = 'bold';
            app.defaultValueBox.Position = [172 (app.disFromBottomMinMaxBox+27) 42 15];
            app.defaultValueBox.Text = num2str(app.minMaxDefaultStepValues(3));
            
            % Create setselectiontodefaultButton
            app.setselectiontodefaultButton = uibutton(app.minMaxDefPanel, 'push');
            app.setselectiontodefaultButton.ButtonPushedFcn = createCallbackFcn(app, @setselectiontodefaultButtonPushed, true);
            app.setselectiontodefaultButton.Position = [95 app.disFromBottomMinMaxBox-3 135 22];
            app.setselectiontodefaultButton.Text = 'set selection to default';
        end
%>>>>END of showMinMaxBoxes

%>>>>> createParameterSlider >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>    
function createParameterSlider(app) %parent is min Max panel
            % Create SliderLabel
            app.SliderLabel = uilabel(app.tabSelection);
            app.SliderLabel.HorizontalAlignment = 'center';
            app.SliderLabel.FontWeight = 'bold';
            app.SliderLabel.FontSize = 14;
            %slider dimensions refer to listbox for 
            SliderTitleLeftPosition = app.tabSelection.Position(1)+1;
            SliderTitleBottomPosition = app.TabGroup.SelectedTab.Position(4)-30; %height starts at 1 bottom from tabselection;
            SliderTitleWidthPosition = 10*(length(app.presentCamSelecOptTable{app.index,3})+17);
            app.SliderLabel.Position = [SliderTitleLeftPosition SliderTitleBottomPosition SliderTitleWidthPosition 19];
            
            paramTitle = app.presentCamSelecOptTable{app.index,3};
            app.SliderLabel.Text = strcat(strcat('c++ parameter name:',{'  '}), paramTitle);
            
            % Create SliderITSELF
            app.SliderITSELF = uislider(app.tabSelection);
            app.SliderITSELF.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.SliderITSELF.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.SliderITSELF.Limits = [app.minMaxDefaultStepValues(1) app.minMaxDefaultStepValues(2)];
            app.SliderITSELF.Value = app.minMaxDefaultStepValues(3);
            app.SliderITSELF.MajorTicks = app.stepsDisplayed;
            app.SliderITSELF.Orientation = 'vertical';
            app.SliderITSELF.MinorTicks = 1;
            sliderLeftPosition = app.TabGroup.SelectedTab.Position(3)-100;
            app.SliderITSELF.Position = [sliderLeftPosition app.listBoxPosition(2) 3 app.listBoxPosition(4)];
            
        end
%>>>>> END of createSlider

%>>>>>>> createSpinner >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        function createSpinner(app)
            % Create ValueSpinnerLabel
            app.ValueSpinnerLabel = uilabel(app.minMaxDefPanel);
            app.ValueSpinnerLabel.HorizontalAlignment = 'right';
            app.ValueSpinnerLabel.Position = [2 120 36 15];
            app.ValueSpinnerLabel.Text = 'value';
            
            % Create ValueSpinner 
            if app.index == 10 %for white balance
                app.ValueSpinner = uispinner(app.minMaxDefPanel,'Step',10);
            else
                app.ValueSpinner = uispinner(app.minMaxDefPanel);
            end
            app.ValueSpinner.Limits = [app.minMaxDefaultStepValues(1) app.minMaxDefaultStepValues(2)];
            app.ValueSpinner.Value = app.SliderITSELF.Value;
            app.ValueSpinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.ValueSpinner.ValueChangingFcn = createCallbackFcn(app, @SpinnerValueChanging, true);
            app.ValueSpinner.Position = [(app.minMaxDefPanel.Position(3)-110) 115 100 22];
        end 
%>>>>> END of createSpinner

%>>>>>>>>>>  SliderValueChanged >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        function SliderValueChanged(app,event) %updates spinner field
            SliderValueNotRoundedToTen = round(app.SliderITSELF.Value);
            
            if app.index ~= 10 % not whiteBalance
                app.ValueSpinner.Value = SliderValueNotRoundedToTen; %rounded to the nearest integer

            else % for white balance, rounds up to next 10th if LSD >= 5; else rounds down
                leastSiginicantDigit = rem(SliderValueNotRoundedToTen,10);%gets the least significatn digit
                if leastSiginicantDigit >= 5
                    while rem(leastSiginicantDigit,10) ~= 0
                        SliderValueNotRoundedToTen = SliderValueNotRoundedToTen + 1;
                        leastSiginicantDigit = leastSiginicantDigit + 1;
                    end
                else
                    while leastSiginicantDigit ~= 0
                        SliderValueNotRoundedToTen = SliderValueNotRoundedToTen - 1;
                        leastSiginicantDigit = leastSiginicantDigit - 1;
                    end
                end
                clear leastSignificantDigit;
                app.ValueSpinner.Value = SliderValueNotRoundedToTen;
            end
            app.SliderITSELF.Value = app.ValueSpinner.Value;  
            app.dev_opt_current_value = SliderValueNotRoundedToTen; %updated value actually
            [~, app.err] = calllib('realsense', 'rs_set_device_option', app.dev{app.selectdev}, app.opt, app.dev_opt_current_value, rs_error);
            rs_check_error(app.err);
            GetOptions(app);
            clear SliderValueNotRoundedToTen;
        end
%>>>>>>>>>>  END of SliderValueChanged

%>>>>>>>>>>  SpinnerValueChanged >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        function SpinnerValueChanged(app,event) % updates slider with values from spinner
             app.SliderITSELF.Value = app.ValueSpinner.Value;
             app.dev_opt_current_value = app.SliderITSELF.Value;
             [~, app.err] = calllib('realsense', 'rs_set_device_option', app.dev{app.selectdev}, app.opt, app.dev_opt_current_value, rs_error);
             rs_check_error(app.err);
             GetOptions(app);
        end
%>>>>>>>>>>  END of SpinnerValueChanged

%>>>>>>>>>>  SpinnerValueChanging >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        function SpinnerValueChanging(app,event) % updates slider with values from spinner
                app.SliderITSELF.Value = event.Value;
                
        end
%>>>>>>>>>>  END of stepsDisplayed

%>>>>>>>>>>  SliderValueChanging >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>   
        function SliderValueChanging(app,event) %updates spinner field
            app.ValueSpinner.Value = event.Value;
            %app.ValueSpinner.Value = round(event.Value);
        end
%>>>>>>>>>>  END of SliderValueChanging

%>>>>>>>>> getCamShortName >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
         %gets the camera's short name from the camera connected
         %saves the short name into app.camShortName
        function getCamShortName(app)
            camNames = {'SR300' 'F200' 'R200' 'Fisheye'};
            numOfSpaces = 1;
            allCamsConnected = strings(1,app.deviceCount);
            word = char.empty(1,0);%no of rows = cams connected
            j = 1;
            for a = 1:app.deviceCount %runs through al connected cameras
                fullName = deblank(char(app.deviceFriendlyName(a))); %eliminates whitespace at the end of name
                for i = 1:length(fullName) 
                    if fullName(i) == ' '
                        if ~isempty(word)
                       %     stringList(numOfSpaces)= string(word);
                            clear word;
                            word=char.empty(1,0);%recreates word
                            numOfSpaces = numOfSpaces + 1;
                        end
                        j = 1; %restarts index for word
                    elseif (i+1)==' '
                        while(i+1) == ' '
                            i = i + 1;
                        end                    
                    else
                        word(j) = fullName(i);
                        j = j + 1; %index for word only
                        if i == length(fullName)
                            if ~isempty(word)
                           %     stringList(numOfSpaces) = string(word)
                                for x = 1:length(camNames) %compares list of possible cameras with connected ones
                                    if (strcmp(word,camNames{1,x}) && ~isempty(allCamsConnected))
                                        allCamsConnected(a) = word;
                                    end
                                end
                                clear word;
                                word=char.empty(1,0);%recreates word
                            end
                        end
                    end
                    
                end                
            end
            app.connctdShortCamNames = cellstr(allCamsConnected);
        end   
%>>>>>>>>> END of getCamShortName 

% >>>>>>>>> dropDownCamChoices >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        function dropDownCamChoices(app)
                %create dropdown for camera selection
            app.CameraDropDown = uidropdown(app.tabSelection);
            app.CameraDropDown.Items = cellstr(categorical(1:app.deviceCount));
            app.CameraDropDown.ValueChangedFcn = createCallbackFcn(app, @CameraDropDownValueChanged, true);
            app.CameraDropDown.Position = [457 287 100 22];
            app.CameraDropDown.Value = '1';
            %app.CameraDropDown.TooltipString = 'Select Camera';
            app.CameraDropDownLabel = uilabel(app.tabSelection);
            app.CameraDropDownLabel.HorizontalAlignment = 'right';
            app.CameraDropDownLabel.Position = [398 318 67 15];
            app.CameraDropDownLabel.Text = 'Camera:';
        end
%>>>>>>>>> END of dropDownCamChoices 

% >>>>>>>>> initializeVariables >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        function initializeVariables(app)
            app.opt = 0; %starts with Contrast
            app.width = 640; 
            app.height = 480;
            app.fps = 30;
            %app.ardobj = arduino;
            %app.axis1 = servo(app.ardobj,'D2');
            %app.axis2 = servo(app.ardobj,'D3');
            loadlibrary('realsense', 'rsutil.h', ...
                        'addheader','rs.h', ...
                        'mfilename', 'realsense');
            [app.methodinfo, app.structs, app.rsEnum, app.ThunkLibName] = realsense;
            [app.ctx, app.err] = calllib('realsense', 'rs_create_context', 11201, rs_error);
            rs_check_error(app.err);
            %new code to get number of cameras connected and make device objects and initialize each
            [app.deviceCount, app.err] = calllib('realsense', 'rs_get_device_count', app.ctx, rs_error());
            rs_check_error(app.err);
            for i = 1:app.deviceCount
                app.devIdx = i - 1;
                [app.dev{i}, app.err] = calllib('realsense', 'rs_get_device', app.ctx, app.devIdx, rs_error);
                rs_check_error(app.err);
                [app.deviceFriendlyName{i}, app.err] = calllib('realsense', 'rs_get_device_name', app.dev{i}, rs_error());
                rs_check_error(app.err);
                [~, app.err] = calllib('realsense', 'rs_enable_stream', app.dev{i}, app.rsEnum.rs_stream.RS_STREAM_COLOR, ...
                    app.width, app.height, app.rsEnum.rs_format.RS_FORMAT_RGB8, app.fps, rs_error);
                rs_check_error(app.err);
                [~, app.err] = calllib('realsense', 'rs_enable_stream', app.dev{i}, app.rsEnum.rs_stream.RS_STREAM_DEPTH, ...
                    app.width, app.height, app.rsEnum.rs_format.RS_FORMAT_Z16, app.fps, rs_error);
                rs_check_error(app.err);
                [~, app.err] = calllib('realsense', 'rs_start_device', app.dev{i}, rs_error);
                rs_check_error(app.err);
                app.depth_intrin{i} = libstruct('rs_intrinsics', struct());
                [~, app.depth_intrin{i}, app.err] = calllib('realsense', 'rs_get_stream_intrinsics', ...
                    app.dev{i}, app.rsEnum.rs_stream.RS_STREAM_DEPTH, app.depth_intrin{i}, rs_error);
                rs_check_error(app.err);
                app.color_intrin{i} = libstruct('rs_intrinsics', struct());
                [~, app.color_intrin{i}, app.err] = calllib('realsense', 'rs_get_stream_intrinsics', ...
                    app.dev{i}, app.rsEnum.rs_stream.RS_STREAM_COLOR, app.color_intrin{i}, rs_error);
                rs_check_error(app.err);
                app.depth_to_color{i} = libstruct('rs_extrinsics', struct());
                [~, app.depth_to_color{i}, app.err] = calllib('realsense', 'rs_get_device_extrinsics', ...
                    app.dev{i}, app.rsEnum.rs_stream.RS_STREAM_DEPTH, app.rsEnum.rs_stream.RS_STREAM_COLOR, app.depth_to_color{i}, rs_error);
                rs_check_error(app.err);
                [app.scale{i}, app.err] = calllib('realsense', 'rs_get_device_depth_scale', app.dev{i}, rs_error);
                rs_check_error(app.err);
            end
            app.selectdev = 1;
            app.index = 1;
            getCamShortName(app);%separates the camera nickname ("SR300", for ex) from human-friendly name
            %initialize variables for ivcam presets
            app.ivcamopts = [18, 19, 20, 21, 22, 23, 24, 25, 26, 27, 12, 13, 15, 16, 14];
            app.ivcampresets = load('ivcampresets.mat'); 
            %initialize stream snapshot variables
            app.countcolor = 0;
            app.countdepth = 0;
            app.countpcl = 0;
            %buttons start at 0
            app.colorstreamButtonFlag = 0;
            app.depthstreamButtonFlag = 0;
            app.allstreamButtonFlag = 0;
            app.pointcloudButtonFlag = 0;
            %timers for stream display scripts
            app.timercolor = timer('TimerFcn', @(x, y) color(app, x, y), 'ExecutionMode', 'fixedRate', 'Period', 1/30, 'Name', 'timercolor');
            app.timerdepth = timer('TimerFcn', @(x, y) depth(app, x, y), 'ExecutionMode', 'fixedRate', 'Period', 1/30, 'Name', 'timerdepth');
            app.timerpcl = timer('TimerFcn', @(x, y) pcl(app, x, y), 'ExecutionMode', 'fixedRate', 'Period', 1/30, 'Name', 'timerpcl');
            app.timerall = timer('TimerFcn', @(x, y) allcam(app, x, y), 'ExecutionMode', 'fixedRate', 'Period', 1/30, 'Name', 'timerall');
            %app.presentCamSelecOptTable = app.selCamOptTable.(app.allTabs(app.selectdev).Title); %X by 10 CELL ARRAY
            %hide figure 1
            %set(figure(1),'Visible','off');
            close(figure(1));
            drawnow;
        end
%>>>>>>>>> END of initializeVariables


        % Value changed function: ivcampresetDropDown
        function ivcampresetDropDownValueChanged(app, event)
            app.ivcamoptvals = cell2mat(app.ivcampresets.ivcampresets...
                (find(strcmp(app.ivcampresets.ivcampresets, app.ivcampresetDropDown.Value)), 2:16));
            setdeviceoptions(app);
            GetOptions(app);
        end
        
        function startupLoad(app)
                        % Create TabGroup
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.AutoResizeChildren = 'off';
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @tabSelectedCallback, true);
            app.TabGroup.Position = [1 80 app.UIFigure.Position(3) (app.UIFigure.Position(4)-80)];
        end
        
        function tabSelectedCallback(app,event)
            %selecting new tab selects new device
            app.tabSelection = app.TabGroup.SelectedTab;
            
            for i=1:app.deviceCount
                if~isempty(strfind(app.allTabs(i).Title,app.tabSelection.Title))
                    app.selectdev = i;%sets new camera selected
                end
            end
            %X by 10 CELL ARRAY, currently selected camera options table    
            
            app.presentCamSelecOptTable = app.selCamOptTable.(app.allTabs(app.selectdev).Title); 
            buildivcamPanel(app);
            app.tabSelection = app.TabGroup.SelectedTab;
            selectedParameter(app, app.paramListBox(1).Value);
        end
        

        % Callback function
        function colorcalibrationButtonPushed(app, event)
            app.figcalorig = figure('Name', 'Original');
            app.axcalorig = axes;
            app.figcalcorr = figure('Name', 'Corrected');
            app.axcalcorr = axes;
            calibrate(app);
        end
        
    end % END of PUBLIC methods


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% PROGRAM STARTUP  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            buildBottomButtons(app);
            startupLoad(app);
            initializeVariables(app);
            buildTabs(app);
            app.TabGroup.SelectedTab = app.allTabs(1);
            %    app.SR300ListBox.Value = app.SR300ListBox.Items(1);
            %    selectedParameter(app, app.SR300ListBox.Value); % starts with slider generated
            
            %app.presentCamSelecOptTable = app.selCamOptTable.(app.allTabs(app.selectdev).Title);
            %app.tabSelection = app.TabGroup.SelectedTab;
            tabSelectedCallback(app,app.tabSelection);
            
            %app.presentCamSelecOptTable = app.selCamOptTable.(app.allTabs(app.selectdev).Title); %X by 10 CELL ARRAY
            %buildSelection(app);
            %selectedParameter(app);
            
        end

        % Callback function
        function CameraDropDownValueChanged(app, event)
            %set selected device to selected camera and get options for new camera selection
            app.selectdev = str2num(app.CameraDropDown.Value);
            GetOptions(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            delete(app);
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
         %   position = app.UIFigure.Position;
        end

        % Callback function
        function allstreamButtonValueChanged(app, event)
            if app.allstreamButton.Value
                %create figure and divide into subaxes for each stream
                app.figall = figure('Name', 'All Cameras');
                for i = 1:2 * app.deviceCount
                    app.axall{i} = subplot(2, app.deviceCount, i);
                end
                start(app.timerall);
            else
                 currFig = get(groot,'CurrentFigure');
                 if ~isempty(currFig)
                    stop(app.timerall);
                    close(app.figall);
                 else
                      app.allstreamButton.Value = 0;
                 end
            end
        end

        % Callback function
        function colorstreamButtonValueChanged(app, event)
            %             if app.colorstreamButtonFlag == 0
            %                 app.colorstreamButtonFlag = 1;
            %                 app.figcolor = figure('Name', 'Color');
            %                 app.axcolor = axes;
            %                 start(app.timercolor);
            %             else
            %                 app.colorstreamButtonFlag = 0;
            %                  currFig = get(groot,'CurrentFigure')
            %                  if ~isempty(currFig)
            %                    stop(app.timercolor);
            %                    close(app.figcolor);
            %                    %app.colorstreamButtonFlag = 0;
            %                  else
            %                     colorstreamButtonValueChanged(app, event);
            %                  end
            %             end
            if app.colorstreamButton.Value
                app.figcolor = figure('Name', 'Color');
                app.axcolor = axes;
                start(app.timercolor);
            else
                 currFig = get(groot,'CurrentFigure')
                 if ~isempty(currFig)
                    stop(app.timercolor);
                    close(app.figcolor);
                 else
                     app.colorstreamButton.Value = 0;
                 end
            end
        end

        % Callback function
        function depthstreamButtonValueChanged(app, event)
           if app.depthstreamButton.Value
                app.figdepth = figure('Name', 'Depth');
                app.axdepth = axes;
                start(app.timerdepth);
           else
                 currFig = get(groot,'CurrentFigure');
                 if ~isempty(currFig)
                    stop(app.timerdepth);
                    close(app.figdepth);
                 else
                      app.depthstreamButton.Value = 0;
                 end
            end
        end

        % Callback function
        function pointcloudButtonValueChanged(app, event)
            if app.pointcloudButton.Value
                app.figpcl = figure('Name', 'Point Cloud');
                app.axpcl = axes;
                start(app.timerpcl);
            else
                 currFig = get(groot,'CurrentFigure');
                 if ~isempty(currFig)
                    stop(app.timerpcl);
                    close(app.figpcl);
                 else
                      app.pointcloudButton.Value = 0;
                 end
            end
        end

        % Callback function
        function selectedParameter(app, event)
            %selecting new tab selects new device
            %             app.tabSelection = app.TabGroup.SelectedTab;
            %             
            %             for i=1:app.deviceCount
            %                 if~isempty(strfind(app.allTabs(i).Title,app.tabSelection.Title))
            %                     app.selectdev = i;%sets new camera selected
            %                 end
            %             end
            %             
            %             app.presentCamSelecOptTable = app.selCamOptTable.(app.allTabs(app.selectdev).Title); %X by 10 CELL ARRAY, currently selected camera options table
            %             buildBottomButtons(app);
            %             buildivcamPanel(app);
            app.index = uint8(0);
            
            titles = app.presentCamSelecOptTable(:,4);%finds the selection and gets its index
            indeces = strfind(cellstr(titles),app.paramListBox(app.selectdev).Value,'ForceCellOutput',true); %test for finding string           
            app.index = find(~cellfun(@isempty,indeces)); %gets real index 1-69 of selection
            
            %use below to write real parameter's name
            app.functionCall =  cell2mat(app.presentCamSelecOptTable(app.index,3));
            app.opt = app.rsEnum.rs_option.(app.functionCall);
            buildSelection(app); %builds slider
            %createDefautOpttButton(app);
            clear titles;
        end

        % Callback function
        function setallparameterstodefaultvaluesButtonPushed(app, event)
            for i = 1:length(app.presentCamSelecOptTable(:,8))
                [~, app.err] = calllib('realsense', 'rs_set_device_option', app.dev{app.selectdev}, i - 1, cell2mat(app.presentCamSelecOptTable(i,8)), rs_error);
                rs_check_error(app.err);
            end
            GetOptions(app);
        end

        % Callback function
        function setselectiontodefaultButtonPushed(app, event)
            [~, app.err] = calllib('realsense', 'rs_set_device_option', app.dev{app.selectdev}, app.opt, app.minMaxDefaultStepValues(3), rs_error);
            rs_check_error(app.err);
            GetOptions(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = SR300_App_vAlpha_v71

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end