classdef optui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        EditField                  matlab.ui.control.NumericEditField
        OptionDropDownLabel        matlab.ui.control.Label
        OptionDropDown             matlab.ui.control.DropDown
        ResetAllOptionsButton      matlab.ui.control.Button
        GeneratePointCloudsButton  matlab.ui.control.Button
        ResetCurrentOptionButton   matlab.ui.control.Button
        ColorStreamButton          matlab.ui.control.Button
        DepthStreamButton          matlab.ui.control.Button
        Axis2EditFieldLabel        matlab.ui.control.Label
        Axis2EditField             matlab.ui.control.NumericEditField
        Axis1EditFieldLabel        matlab.ui.control.Label
        Axis1EditField             matlab.ui.control.NumericEditField
    end


    properties (Access = public)
         dev
         dev_opt_desc
         dev_opt_value
         opt
         optindex
         optsupported
         dev_opt_current
         dev_opt_default
         opt_min
         opt_max
         opt_step
         rsoptions
         width
         height
         fps
         ctx
         rsEnum
         err
         rsoptionscount
         depth_intrin
         color_intrin
         depth_to_color
         methodinfo
         structs
         ThunkLibName
         scale
         ardobj
         axis1
         axis2
    end

    methods (Access = public)
    
        function GetOptions(app)
            for i = 1:app.rsoptionscount
                app.opt = app.rsoptions{i};
                [app.optsupported(i), ~, app.err] = calllib('realsense', 'rs_device_supports_option', app.dev, app.opt, rs_error);
                rs_check_error(app.err);
            
                [app.dev_opt_desc{i}, ~, app.err] = calllib('realsense', 'rs_get_device_option_description', app.dev, app.opt, rs_error);
                rs_check_error(app.err);
            
                [app.dev_opt_current(i), ~, app.err] = calllib('realsense', 'rs_get_device_option', app.dev, app.opt, rs_error);
                rs_check_error(app.err);
                [~, app.opt_min(i), app.opt_max(i), app.opt_step(i), app.err] = calllib('realsense', 'rs_get_device_option_range', ...
                     app.dev, app.opt, app.opt_min(i), app.opt_max(i), app.opt_step(i), rs_error);
                rs_check_error(app.err);
            end
            app.opt = app.rsEnum.rs_option.(app.OptionDropDown.Value);
            app.optindex = find(ismember(app.rsoptions, app.OptionDropDown.Value));
            app.dev_opt_value = app.dev_opt_current(app.optindex);
            app.EditField.Value = app.dev_opt_value;
        end
        
    end

    methods (Access = public)
    
        function ResetAllOptions(app)
            for i = 1:app.rsoptionscount
                app.opt = app.rsoptions{i};
                [~, app.err] = calllib('realsense', 'rs_reset_device_options_to_default', app.dev, app.opt, 1, rs_error);
                rs_check_error(app.err);
            end            
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.width = 640; 
            app.height = 480;
            app.fps = 30;
            app.ardobj = arduino;
            app.axis1 = servo(app.ardobj,'D2');
            app.axis2 = servo(app.ardobj,'D3');
            loadlibrary('realsense', 'rsutil.h', ...
                        'addheader','rs.h', ...
                        'mfilename', 'realsense');
            [app.methodinfo, app.structs, app.rsEnum, app.ThunkLibName] = realsense;
            [app.ctx, app.err] = calllib('realsense', 'rs_create_context', 11201, rs_error);
            rs_check_error(app.err);
            [app.dev, app.err] = calllib('realsense', 'rs_get_device', app.ctx, 0, rs_error);
            rs_check_error(app.err);
            [~, app.err] = calllib('realsense', 'rs_enable_stream', app.dev, app.rsEnum.rs_stream.RS_STREAM_COLOR, ...
                app.width, app.height, app.rsEnum.rs_format.RS_FORMAT_RGB8, app.fps, rs_error);
            rs_check_error(app.err);
            [~, app.err] = calllib('realsense', 'rs_enable_stream', app.dev, app.rsEnum.rs_stream.RS_STREAM_DEPTH, ...
                app.width, app.height, app.rsEnum.rs_format.RS_FORMAT_Z16, app.fps, rs_error);
            rs_check_error(app.err);
            [~, app.err] = calllib('realsense', 'rs_start_device', app.dev, rs_error);
            rs_check_error(app.err);
            app.depth_intrin = libstruct('rs_intrinsics', struct());
            [~, app.depth_intrin, app.err] = calllib('realsense', 'rs_get_stream_intrinsics', ...
                app.dev, app.rsEnum.rs_stream.RS_STREAM_DEPTH, app.depth_intrin, rs_error);
            rs_check_error(app.err);
            app.color_intrin = libstruct('rs_intrinsics', struct());
            [~, app.color_intrin, app.err] = calllib('realsense', 'rs_get_stream_intrinsics', ...
                app.dev, app.rsEnum.rs_stream.RS_STREAM_COLOR, app.color_intrin, rs_error);
            rs_check_error(app.err);
            app.depth_to_color = libstruct('rs_extrinsics', struct());
            [~, app.depth_to_color, app.err] = calllib('realsense', 'rs_get_device_extrinsics', ...
                app.dev, app.rsEnum.rs_stream.RS_STREAM_DEPTH, app.rsEnum.rs_stream.RS_STREAM_COLOR, app.depth_to_color, rs_error);
            rs_check_error(app.err);
            [app.scale, app.err] = calllib('realsense', 'rs_get_device_depth_scale', app.dev, rs_error);
            rs_check_error(app.err);
            app.rsoptions = fieldnames(app.rsEnum.rs_option);
            app.dev_opt_desc = app.rsoptions;
            app.rsoptionscount = numel(app.rsoptions);
            app.dev_opt_current = zeros(app.rsoptionscount, 1);
            app.opt_min = zeros(app.rsoptionscount, 1);
            app.opt_max = zeros(app.rsoptionscount, 1);
            app.opt_step = zeros(app.rsoptionscount, 1);
            app.optsupported = zeros(app.rsoptionscount, 1);
            %ResetAllOptions(app);
            GetOptions(app);
            app.dev_opt_default = app.dev_opt_current;
        end

        % Value changed function: Axis1EditField
        function Axis1EditFieldValueChanged(app, event)
            writePosition(app.axis1,app.Axis1EditField.Value);
        end

        % Value changed function: Axis2EditField
        function Axis2EditFieldValueChanged(app, event)
            writePosition(app.axis2,app.Axis2EditField.Value);
        end

        % Button pushed function: ColorStreamButton
        function ColorStreamButtonPushed(app, event)
            color
        end

        % Button pushed function: DepthStreamButton
        function DepthStreamButtonPushed(app, event)
            depth
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.dev_opt_value = app.EditField.Value;
            [~, app.err] = calllib('realsense', 'rs_set_device_option', app.dev, app.opt, app.dev_opt_value, rs_error);
            rs_check_error(app.err);
            GetOptions(app);
        end

        % Button pushed function: GeneratePointCloudsButton
        function GeneratePointCloudsButtonPushed(app, event)
            generate
        end

        % Value changed function: OptionDropDown
        function OptionDropDownValueChanged(app, event)
            GetOptions(app);
        end

        % Button pushed function: ResetAllOptionsButton
        function ResetAllOptionsButtonPushed(app, event)
            ResetAllOptions(app);
            GetOptions(app);
        end

        % Button pushed function: ResetCurrentOptionButton
        function ResetCurrentOptionButtonPushed(app, event)
            [~, app.err] = calllib('realsense', 'rs_set_device_option', app.dev, app.opt, app.dev_opt_default(app.optindex), rs_error);
            rs_check_error(app.err);
            GetOptions(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.RoundFractionalValues = 'on';
            app.EditField.ValueDisplayFormat = '%.0f';
            app.OptionDropDownLabel = uilabel(app.UIFigure);
            app.OptionDropDownLabel.Text = 'Option';
            app.OptionDropDown = uidropdown(app.UIFigure);
            app.OptionDropDown.Items = {'RS_OPTION_COLOR_BACKLIGHT_COMPENSATION', 'RS_OPTION_COLOR_BRIGHTNESS', 'RS_OPTION_COLOR_CONTRAST', 'RS_OPTION_COLOR_EXPOSURE', 'RS_OPTION_COLOR_GAIN', 'RS_OPTION_COLOR_GAMMA', 'RS_OPTION_COLOR_HUE', 'RS_OPTION_COLOR_SATURATION', 'RS_OPTION_COLOR_SHARPNESS', 'RS_OPTION_COLOR_WHITE_BALANCE', 'RS_OPTION_COLOR_ENABLE_AUTO_EXPOSURE', 'RS_OPTION_COLOR_ENABLE_AUTO_WHITE_BALANCE', 'RS_OPTION_F200_LASER_POWER', 'RS_OPTION_F200_ACCURACY', 'RS_OPTION_F200_MOTION_RANGE', 'RS_OPTION_F200_FILTER_OPTION', 'RS_OPTION_F200_CONFIDENCE_THRESHOLD', 'RS_OPTION_F200_DYNAMIC_FPS', 'RS_OPTION_SR300_AUTO_RANGE_ENABLE_MOTION_VERSUS_RANGE', 'RS_OPTION_SR300_AUTO_RANGE_ENABLE_LASER', 'RS_OPTION_SR300_AUTO_RANGE_MIN_MOTION_VERSUS_RANGE', 'RS_OPTION_SR300_AUTO_RANGE_MAX_MOTION_VERSUS_RANGE', 'RS_OPTION_SR300_AUTO_RANGE_START_MOTION_VERSUS_RANGE', 'RS_OPTION_SR300_AUTO_RANGE_MIN_LASER', 'RS_OPTION_SR300_AUTO_RANGE_MAX_LASER', 'RS_OPTION_SR300_AUTO_RANGE_START_LASER', 'RS_OPTION_SR300_AUTO_RANGE_UPPER_THRESHOLD', 'RS_OPTION_SR300_AUTO_RANGE_LOWER_THRESHOLD', 'RS_OPTION_R200_LR_AUTO_EXPOSURE_ENABLED', 'RS_OPTION_R200_LR_GAIN', 'RS_OPTION_R200_LR_EXPOSURE', 'RS_OPTION_R200_EMITTER_ENABLED', 'RS_OPTION_R200_DEPTH_UNITS', 'RS_OPTION_R200_DEPTH_CLAMP_MIN', 'RS_OPTION_R200_DEPTH_CLAMP_MAX', 'RS_OPTION_R200_DISPARITY_MULTIPLIER', 'RS_OPTION_R200_DISPARITY_SHIFT', 'RS_OPTION_R200_AUTO_EXPOSURE_MEAN_INTENSITY_SET_POINT', 'RS_OPTION_R200_AUTO_EXPOSURE_BRIGHT_RATIO_SET_POINT', 'RS_OPTION_R200_AUTO_EXPOSURE_KP_GAIN', 'RS_OPTION_R200_AUTO_EXPOSURE_KP_EXPOSURE', 'RS_OPTION_R200_AUTO_EXPOSURE_KP_DARK_THRESHOLD', 'RS_OPTION_R200_AUTO_EXPOSURE_TOP_EDGE', 'RS_OPTION_R200_AUTO_EXPOSURE_BOTTOM_EDGE', 'RS_OPTION_R200_AUTO_EXPOSURE_LEFT_EDGE', 'RS_OPTION_R200_AUTO_EXPOSURE_RIGHT_EDGE', 'RS_OPTION_R200_DEPTH_CONTROL_ESTIMATE_MEDIAN_DECREMENT', 'RS_OPTION_R200_DEPTH_CONTROL_ESTIMATE_MEDIAN_INCREMENT', 'RS_OPTION_R200_DEPTH_CONTROL_MEDIAN_THRESHOLD', 'RS_OPTION_R200_DEPTH_CONTROL_SCORE_MINIMUM_THRESHOLD', 'RS_OPTION_R200_DEPTH_CONTROL_SCORE_MAXIMUM_THRESHOLD', 'RS_OPTION_R200_DEPTH_CONTROL_TEXTURE_COUNT_THRESHOLD', 'RS_OPTION_R200_DEPTH_CONTROL_TEXTURE_DIFFERENCE_THRESHOLD', 'RS_OPTION_R200_DEPTH_CONTROL_SECOND_PEAK_THRESHOLD', 'RS_OPTION_R200_DEPTH_CONTROL_NEIGHBOR_THRESHOLD', 'RS_OPTION_R200_DEPTH_CONTROL_LR_THRESHOLD', 'RS_OPTION_FISHEYE_EXPOSURE', 'RS_OPTION_FISHEYE_GAIN', 'RS_OPTION_FISHEYE_STROBE', 'RS_OPTION_FISHEYE_EXTERNAL_TRIGGER', 'RS_OPTION_FISHEYE_ENABLE_AUTO_EXPOSURE', 'RS_OPTION_FISHEYE_AUTO_EXPOSURE_MODE', 'RS_OPTION_FISHEYE_AUTO_EXPOSURE_ANTIFLICKER_RATE', 'RS_OPTION_FISHEYE_AUTO_EXPOSURE_PIXEL_SAMPLE_RATE', 'RS_OPTION_FISHEYE_AUTO_EXPOSURE_SKIP_FRAMES', 'RS_OPTION_FRAMES_QUEUE_SIZE', 'RS_OPTION_HARDWARE_LOGGER_ENABLED', 'RS_OPTION_TOTAL_FRAME_DROPS'};
            app.OptionDropDown.ValueChangedFcn = createCallbackFcn(app, @OptionDropDownValueChanged, true);
            app.OptionDropDown.Value = 'RS_OPTION_COLOR_BACKLIGHT_COMPENSATION';
            app.ResetAllOptionsButton = uibutton(app.UIFigure, 'push');
            app.ResetAllOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAllOptionsButtonPushed, true);
            app.ResetAllOptionsButton.Text = 'Reset All Options';
            app.GeneratePointCloudsButton = uibutton(app.UIFigure, 'push');
            app.GeneratePointCloudsButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePointCloudsButtonPushed, true);
            app.GeneratePointCloudsButton.Text = 'Generate Point Clouds';
            app.ResetCurrentOptionButton = uibutton(app.UIFigure, 'push');
            app.ResetCurrentOptionButton.ButtonPushedFcn = createCallbackFcn(app, @ResetCurrentOptionButtonPushed, true);
            app.ResetCurrentOptionButton.Text = 'Reset Current Option';
            app.ColorStreamButton = uibutton(app.UIFigure, 'push');
            app.ColorStreamButton.ButtonPushedFcn = createCallbackFcn(app, @ColorStreamButtonPushed, true);
            app.ColorStreamButton.Text = 'Color Stream';
            app.DepthStreamButton = uibutton(app.UIFigure, 'push');
            app.DepthStreamButton.ButtonPushedFcn = createCallbackFcn(app, @DepthStreamButtonPushed, true);
            app.DepthStreamButton.Text = 'Depth Stream';
            app.Axis2EditFieldLabel = uilabel(app.UIFigure);
            app.Axis2EditFieldLabel.Text = 'Axis 2';
            app.Axis2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Axis2EditField.ValueChangedFcn = createCallbackFcn(app, @Axis2EditFieldValueChanged, true);
            app.Axis2EditField.Limits = [0 1];
            app.Axis2EditField.ValueDisplayFormat = '%.3f';
            app.Axis1EditFieldLabel = uilabel(app.UIFigure);
            app.Axis1EditFieldLabel.Text = 'Axis 1';
            app.Axis1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Axis1EditField.ValueChangedFcn = createCallbackFcn(app, @Axis1EditFieldValueChanged, true);
            app.Axis1EditField.Limits = [0 1];
            app.Axis1EditField.ValueDisplayFormat = '%.3f';
        end
    end

    methods (Access = public)

        % Construct app
        function app = optui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end