classdef SR300_App_vAlpha < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        SR300Tab                     matlab.ui.container.Tab
        SR300ListBox                 matlab.ui.control.ListBox
        setselectiontodefaultButton  matlab.ui.control.Button
        setallparameterstodefaultvaluesButton  matlab.ui.control.Button
        allstreamButton              matlab.ui.control.StateButton
        colorstreamButton            matlab.ui.control.StateButton
        depthstreamButton            matlab.ui.control.StateButton
        pointcloudButton             matlab.ui.control.StateButton
        ivcampresetDropDownLabel     matlab.ui.control.Label
        ivcampresetDropDown          matlab.ui.control.DropDown
        F200Tab                      matlab.ui.container.Tab
        F200ListBoxLabel             matlab.ui.control.Label
        F200ListBox                  matlab.ui.control.ListBox
        R200Tab                      matlab.ui.container.Tab
        FisheyeTab                   matlab.ui.container.Tab
    end

%This program shows options for paramters with the SR300
%requires optionstable.mat
    properties (Access = public)
        optionTable
        %slider creation
        SliderMinMaxText           matlab.ui.control.Label
        SliderMinValue             matlab.ui.control.Label
        SliderMaxValue             matlab.ui.control.Label
        SliderText                 matlab.ui.control.Label
        SliderLabel                matlab.ui.control.Label
        SliderITSELF               matlab.ui.control.Slider
        ValueSpinnerLabel          matlab.ui.control.Label
        ValueSpinner               matlab.ui.control.Spinner
        defaultValueBoxLabel       matlab.ui.control.Label
        defaultValueBox            matlab.ui.control.Label
        CameraDropDownLabel        matlab.ui.control.Label
        CameraDropDown             matlab.ui.control.DropDown
        
        stepsDisplayed % for saving array of 11 elements to be displayed on slider
        optionsTitlesCamera %titles, descriptions, and parameters
        %num array for defaul, min, and max values
        minMaxDefaultStepValues %increases increment/decrement step to 10
        whiteBalanceIndex 
        %camera %name of the camera
        tabSelection %selected tab Object; app.tabSelection.Title for string with title
        index
        functionCall %for saving the paramter/function's name
        
%%%%%%% C++ library variables/objects %%%%%%%%%%%%%%%%%%
        dev %pointer - camera
        opt %from 0 to 68 (corresponds to functions' names
        dev_opt_current_value %t
        rsoptions
        width
        height
        fps
        ctx
        rsEnum
        err
        rsoptionscount
        depth_intrin
        color_intrin
        depth_to_color
        methodinfo
        structs
        ThunkLibName
        scale
        ardobj
        %variables for selecting camera
        devIdx
        deviceCount
        selectdev
        %timer variables
        timercolor
        timerdepth
        timerpcl
        timerall
        %axes and figure handles
        figcolor
        figdepth
        figpcl
        figall
        axcolor
        axdepth
        axpcl
        axall
        %stream snapshot counts
        countcolor
        countdepth
        countpcl
        %variables for loading ivcam presets
        ivcamopts
        ivcamoptvals
        ivcampresets
    end

    methods (Access = private)
%>>>>>>>>> GetOptions >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        function GetOptions(app)
            %
            [app.dev_opt_current_value, ~, app.err] = calllib('realsense', 'rs_get_device_option', app.dev{app.selectdev}, app.opt, rs_error);
            rs_check_error(app.err);
            app.ValueSpinner.Value = app.dev_opt_current_value;
            app.SliderITSELF.Value = app.ValueSpinner.Value;
         end
%>>>>>>>>>> END of GetOptioons
       
%>>>>>>>>>>  buildSelection >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        % app required parameter
        % minMax is a 2 element horizontal integers vector 
                % 1st element = min 
                % 2nd = MAX values for parameter
        % description is a cell-array with comments about the parameter
            %the cell-array has syntax {'blah blah blah';'blah blah blah'} - the ; (semicolon) for newline
        % discreteOrNot can be any number  for discrete slider or 0 for nondiscrete
        % stepsDisplayed is a function that generates a vector with up to 10 elements
        % WORKS FOR PARAMATERS WITH STEP OF 1 (positive integer)
%        function buildSelection(app, title, index)
        function buildSelection(app)
            erasePreviousSelection(app); % clears previous slider and number boxes
            if app.index == 10 %controls steps for whiteBalance
                 app.whiteBalanceIndex=1;
             else
                 app.whiteBalanceIndex = 0;                  
            end
            %creates array of doubles min, max, default, and step parameter values from cell array
            %o
            app.minMaxDefaultStepValues = cell2mat([app.optionTable(app.index,5);app.optionTable(app.index,6);app.optionTable(app.index,4);app.optionTable(app.index,7)]);
            %optionsTable(index,)
            getValuesDispBySlider(app); %produces array with values to be shown by slider
            buildDefaultValueBox(app);
            buildMinMaxBoxes(app);%shows boxes with min and max possible values
            createParameterSlider(app); %shows new slider
            createSpinner(app); % shows pinner
            GetOptions(app);%interfaces with hardware
        end
%>>>>>>>>>>  END of buildSelection

%>>>>>>>>>>  erasePreviousSelection >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        function erasePreviousSelection(app) %clears previous selection
            delete(app.SliderMinValue);
            delete(app.SliderMinMaxText);
            delete(app.SliderMaxValue);
            delete(app.SliderText);
            delete(app.SliderLabel);
            delete(app.SliderITSELF);
            delete(app.ValueSpinnerLabel);
            delete(app.ValueSpinner);
            clear app.whiteBalanceIndex;
            clear app.minMaxDefaultStepValues;
            clear app.stepsDisplayed;
        end
%>>>>>>>>>>  END of erasePreviousSelection

%>>>>>>>>>>  stepsDisplayed >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
%calcultes which and how many steps the slider has
function getValuesDispBySlider(app)
            rangeBetweenMinMax = abs(app.minMaxDefaultStepValues(2)-app.minMaxDefaultStepValues(1));
            
            if rangeBetweenMinMax >10  % if abs(max - min) > 10, defines 10 steps
                step = (app.minMaxDefaultStepValues(2)-app.minMaxDefaultStepValues(1))/10;
                app.stepsDisplayed = zeros(1,11);
                app.stepsDisplayed(1) = app.minMaxDefaultStepValues(1); %initializes smallest value
                app.stepsDisplayed(11) = app.minMaxDefaultStepValues(2); %initializes smallest value
                for i = 2:10 %initializes slider min < (slider values) < max 
                   app.stepsDisplayed(i) = round(step + app.stepsDisplayed(i-1));
                end
                app.stepsDisplayed(6) = app.minMaxDefaultStepValues(3);
                %app.dev_opt_current_value;%center of slide = current value (not necessarily shown)
            else
                app.stepsDisplayed = zeros(1, rangeBetweenMinMax +1);%+1 to make sure the max value will be shown in arrays with 0 values
                app.stepsDisplayed(1) = app.minMaxDefaultStepValues(1);
                app.stepsDisplayed(rangeBetweenMinMax+1) = app.minMaxDefaultStepValues(2); % rangeBetweenMinMax alsp accounts max = 0 adn min < 0      
                for i = 2:rangeBetweenMinMax %initializes slider min < (slider values) < max 
                   app.stepsDisplayed(i) = app.stepsDisplayed(i-1)+1;
                end
            end
        end
%>>>>>>>>>>  END of getValuesDispBySlider

%>>>>>>>>> showMinMaxBoxes >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        function buildMinMaxBoxes(app)
            % Create SliderMinMaxText
            app.SliderMinMaxText = uilabel(app.tabSelection);
            app.SliderMinMaxText.Position = [330 480 28 42];
            app.SliderMinMaxText.Text = {'max'; ''; 'min'};
            
            % Create SliderMaxValue - box at the slider's left-hand side
            app.SliderMaxValue = uilabel(app.tabSelection);
            app.SliderMaxValue.BackgroundColor = [0.8588 0.9451 0.9882];
            app.SliderMaxValue.HorizontalAlignment = 'right';
            app.SliderMaxValue.FontWeight = 'bold';
            app.SliderMaxValue.Position = [365 507 42 15];
            app.SliderMaxValue.Text = num2str(app.minMaxDefaultStepValues(2));

            % Create SliderMinValue - box at the slider's left-hand side             
            app.SliderMinValue = uilabel(app.tabSelection);
            app.SliderMinValue.BackgroundColor = [0.8588 0.9451 0.9882];
            app.SliderMinValue.HorizontalAlignment = 'right';
            app.SliderMinValue.FontWeight = 'bold';
            app.SliderMinValue.Position = [365 478 42 15];
            app.SliderMinValue.Text = num2str(app.minMaxDefaultStepValues(1));
            %app.SliderMinValue.Text = num2str(minMax(1))

        end

%>>>>END of showMinMaxBoxes

%>>>>>>>>> buildDefaultValueButton&Box >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        function buildDefaultValueBox(app)
            % Create defaultValueBoxLabel
            app.defaultValueBoxLabel = uilabel(app.tabSelection);
            app.defaultValueBoxLabel.Position = [430 480 50 42];
            app.defaultValueBoxLabel.FontWeight = 'normal';
            app.defaultValueBoxLabel.HorizontalAlignment = 'center';
            app.defaultValueBoxLabel.Text = {'default'};
            
            % Create defaultValueBox - box at the slider's left-hand side
            app.defaultValueBox = uilabel(app.tabSelection);
            app.defaultValueBox.BackgroundColor = [0.8588 0.9451 0.9882];
            app.defaultValueBox.HorizontalAlignment = 'right';
            app.defaultValueBox.FontWeight = 'bold';
            app.defaultValueBox.Position = [500 507 42 15];
            app.defaultValueBox.Text = num2str(app.minMaxDefaultStepValues(3));
        end

%>>>>END of showMinMaxBoxes

%>>>>> createParameterSlider >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>    
    function createParameterSlider(app)
            % Create SliderLabel
            app.SliderLabel = uilabel(app.tabSelection);
            app.SliderLabel.HorizontalAlignment = 'center';
            app.SliderLabel.FontWeight = 'bold';
            app.SliderLabel.Position = [330 540 250 15];
            app.SliderLabel.Text = char(cell2mat(app.optionTable(app.index,2)));

            % Create SliderITSELF
            app.SliderITSELF = uislider(app.tabSelection);
            app.SliderITSELF.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.SliderITSELF.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.SliderITSELF.Limits = [app.minMaxDefaultStepValues(1) app.minMaxDefaultStepValues(2)];
            app.SliderITSELF.Value = app.minMaxDefaultStepValues(3);
            app.SliderITSELF.MajorTicks = app.stepsDisplayed;
            app.SliderITSELF.Orientation = 'vertical';
            app.SliderITSELF.MinorTicks = 1;
            app.SliderITSELF.Position = [600.6875 165 3 380];
            
            % Create Slider Description
            app.SliderText = uilabel(app.tabSelection);
            app.SliderText.Position = [270 400 270 60];
            app.SliderText.HorizontalAlignment = 'right';
            app.SliderText.Text = writeDescWithParagraphs(app.optionTable(app.index,3),4); %second argument isnumber of words per line
        end
%>>>>> END of createSlider

%>>>>>>> createSpinner >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        function createSpinner(app)
            % Create ValueSpinnerLabel
            app.ValueSpinnerLabel = uilabel(app.tabSelection);
            app.ValueSpinnerLabel.HorizontalAlignment = 'right';
            app.ValueSpinnerLabel.Position = [394.375 370 36 15];
            app.ValueSpinnerLabel.Text = 'value';

            % Create ValueSpinner
            app.ValueSpinner = uispinner(app.tabSelection);
            app.ValueSpinner.Limits = [app.minMaxDefaultStepValues(1) app.minMaxDefaultStepValues(2)];
            app.ValueSpinner.Value = app.SliderITSELF.Value;
            app.ValueSpinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.ValueSpinner.ValueChangingFcn = createCallbackFcn(app, @SpinnerValueChanging, true);
            app.ValueSpinner.Position = [453 367 100 22];
        end 
%>>>>> END of createSpinner

%>>>>>>>>>>  SliderValueChanged >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        %whiteBalanceIndex: if ~= 0, step cahnges from 1 to 10 (only parameter with steps of 1)
        function SliderValueChanged(app,event) %updates spinner field
            if app.whiteBalanceIndex == 0
                app.ValueSpinner.Value = round(app.SliderITSELF.Value); %rounded to the nearest integer
            else
                app.ValueSpinner.Value = round(10*app.SliderITSELF.Value); %rounded to the nearest integer
            end
            app.SliderITSELF.Value = app.ValueSpinner.Value;  
            app.dev_opt_current_value = app.SliderITSELF.Value;
            [~, app.err] = calllib('realsense', 'rs_set_device_option', app.dev{app.selectdev}, app.opt, app.dev_opt_current_value, rs_error);
            rs_check_error(app.err);
            GetOptions(app);
        end
%>>>>>>>>>>  END of SliderValueChanged

%>>>>>>>>>>  SliderValueChanging >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        %whiteBalanceIndex: if ~= 0, step cahnges from 1 to 10 (only parameter with steps of 1)        
        function SliderValueChanging(app,event) %updates spinner field
            if app.whiteBalanceIndex == 0
                app.ValueSpinner.Value = round(event.Value);
            else
                app.ValueSpinner.Value = round(10*event.Value);
            end
        end
%>>>>>>>>>>  END of SliderValueChanging

%>>>>>>>>>>  SpinnerValueChanged >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        %whiteBalanceIndex: if ~= 0, step cahnges from 1 to 10 (only parameter with steps of 1)
        function SpinnerValueChanged(app,event) % updates slider with values from spinner
                app.SliderITSELF.Value = app.ValueSpinner.Value;
                app.dev_opt_current_value = app.SliderITSELF.Value;
                [~, app.err] = calllib('realsense', 'rs_set_device_option', app.dev{app.selectdev}, app.opt, app.dev_opt_current_value, rs_error);
                rs_check_error(app.err);
                GetOptions(app);
        end
%>>>>>>>>>>  END of SpinnerValueChanged

%>>>>>>>>>>  SpinnerValueChanging >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        %whiteBalanceIndex: if ~= 0, step cahnges from 1 to 10 (only parameter with steps of 1)
        function SpinnerValueChanging(app,event) % updates slider with values from spinner
            app.SliderITSELF.Value = event.Value;
        end
%>>>>>>>>>>  END of stepsDisplayed

%>>>>>>>>>> optionTitleReturned >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        %gets a list of paramater titles
        %parameters 
            % cam = 'SR300','R200',F200','ALL' (ALL for options that all cameras can use
        function optionTitleReturned = getOptionsTitles(app) %gets titles from mat file with 69x7 cell array
            %optionstable.mat is a 69x7 cell array with specs
                % optionsTable(1-69,2) - cameras
                % optionsTable(1-69,2) - titles
                % optionsTable(1-69,3) - descriptions - must be passed as cell arrayswith semicolon for new paragraph
                % optionsTable(1-69,4) - minMaxDefaultValue(1) = default value
                % optionsTable(1-69,5) - minMaxDefaultValue(2)  = minValue
                % optionsTable(1-69,6) - minMaxDefaultValue(3) = maxValue
                % optionsTable(1-69,7) - step size - white balance has large value; all others are one
                % optionsTable(1-69,8) - placeHolder8
                % GENERAL: optionsTable(1-12,i), i = 1..7
                % 	 optionsTable(66-68,i), i = 1..7
                % F200:  optionsTable(13-18,i), i = 1..7F200
                % SR300 AUTO RANGE OPTIONS:  optionsTable(19-28,i), i = 1..7
                % 
                % R200 AUTO RANGE OPTIONS:  optionsTable(29-56,i), i = 1..7 %NO VALUES AVAILABLE
                % FISHEYE:   optionsTable(57-65,i), i = 1..7 %NO VALUES AVAILABLE
                % 	 optionsTable(69,i), i = 1..7 NO VALUES AVAILABLE FOR OPTION 69
            switch app.tabSelection.Title
                case 'SR300'
                        optionTitleReturned = [app.optionTable(1:12,2);app.optionTable(19:28,2)];
                        app.optionsTitlesCamera = char(optionTitleReturned);
                case 'F200'
                        optionTitleReturned = app.optionTable(12:18,2);
                        app.optionsTitlesCamera = char(optionTitleReturned);
%                 case 'R200'
%                         optionTitleReturned = optionsTable(29:56,2);
%                         app.optionsTitlesCamera = char(optionTitleReturned);
%                 case 'Fisheye'
%                         optionTitleReturned = optionsTable(57:65,2);
%                         app.optionsTitlesCamera = char(optionTitleReturned);

            end
        end 
%>>>>>>>>> % END of optionTitleReturned

%>>>>>>>>>>  writeDescrInTwoOrMoreLines >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        %breaks one line of description into two or more lines
        %description is a cell array, column 3 of optionsTable
%         function  description = writeDescrInTwoOrMoreLines(app)
%             %description = char(cell2mat(app.optionTable(app.index,3)));
%             %description = char(cell2mat(app.optionTable(app.index,3)));
%             newDescriptionWihtParagraphs = strings(1,length(description));
%             numOfSpaces = 0;
%             for i = 1:(length(description)-1)
%                while description(i) ~= ' '
%                    newDescriptionWihtParagraphs(i) = description(i);
%                    i = i + 1
%                end
%                numOfSpaces = numOfSpaces + 1;
%                if rem(numOfSpaces,3) == 0
%                    i = i + 1;
%                    newDescriptionWihtParagraphs = [newDescriptionWihtParagraphs;description(i)]
%                end
%             end
%             description = newDescriptionWihtParagraphs;
%         end


% function  description = writeDescrInTwoOrMoreLines(app)
%             description = strtrim(string(cell2mat(app.optionTable(app.index,3))));
%             newDescriptionWihtParagraphs = strings;
%             previousThree = strings;
%             presentThree strings;
%             numOfSpaces = 0;
% 
%             for j = 1:(length(description))
%                 if rem(j,3) == 0
%                     previousThree = newDescriptionWihtParagraphs;
%                     delete newDescriptionWihtParagraphs;
%                     if j > 5
%                         presentThree = newDescriptionWihtParagraphs;
%                     end
%                 end
%                 newDescriptionWihtParagraphs(j) = description(j);
%             end
%             description = newDescriptionWihtParagraphs
% end 

%>>>>>>>>>>  END of writeDescrInTwoOrMoreLines 

    end % END of PUBLIC methods


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% PROGRAM STARTUP  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.opt = 0; %starts with Contrast
            app.width = 640; 
            app.height = 480;
            app.fps = 30;
            %app.ardobj = arduino;
            %app.axis1 = servo(app.ardobj,'D2');
            %app.axis2 = servo(app.ardobj,'D3');
            loadlibrary('realsense', 'rsutil.h', ...
                        'addheader','rs.h', ...
                        'mfilename', 'realsense');
            [app.methodinfo, app.structs, app.rsEnum, app.ThunkLibName] = realsense;
            [app.ctx, app.err] = calllib('realsense', 'rs_create_context', 11201, rs_error);
            rs_check_error(app.err);
            %new code to get number of cameras connected and make device objects and initialize each
            [app.deviceCount, app.err] = calllib('realsense', 'rs_get_device_count', app.ctx, rs_error());
            rs_check_error(app.err);
            for i = 1:app.deviceCount
                app.devIdx = i - 1;
                [app.dev{i}, app.err] = calllib('realsense', 'rs_get_device', app.ctx, app.devIdx, rs_error);
                rs_check_error(app.err);
                [~, app.err] = calllib('realsense', 'rs_enable_stream', app.dev{i}, app.rsEnum.rs_stream.RS_STREAM_COLOR, ...
                    app.width, app.height, app.rsEnum.rs_format.RS_FORMAT_RGB8, app.fps, rs_error);
                rs_check_error(app.err);
                [~, app.err] = calllib('realsense', 'rs_enable_stream', app.dev{i}, app.rsEnum.rs_stream.RS_STREAM_DEPTH, ...
                    app.width, app.height, app.rsEnum.rs_format.RS_FORMAT_Z16, app.fps, rs_error);
                rs_check_error(app.err);
                [~, app.err] = calllib('realsense', 'rs_start_device', app.dev{i}, rs_error);
                rs_check_error(app.err);
                app.depth_intrin{i} = libstruct('rs_intrinsics', struct());
                [~, app.depth_intrin{i}, app.err] = calllib('realsense', 'rs_get_stream_intrinsics', ...
                    app.dev{i}, app.rsEnum.rs_stream.RS_STREAM_DEPTH, app.depth_intrin{i}, rs_error);
                rs_check_error(app.err);
                app.color_intrin{i} = libstruct('rs_intrinsics', struct());
                [~, app.color_intrin{i}, app.err] = calllib('realsense', 'rs_get_stream_intrinsics', ...
                    app.dev{i}, app.rsEnum.rs_stream.RS_STREAM_COLOR, app.color_intrin{i}, rs_error);
                rs_check_error(app.err);
                app.depth_to_color{i} = libstruct('rs_extrinsics', struct());
                [~, app.depth_to_color{i}, app.err] = calllib('realsense', 'rs_get_device_extrinsics', ...
                    app.dev{i}, app.rsEnum.rs_stream.RS_STREAM_DEPTH, app.rsEnum.rs_stream.RS_STREAM_COLOR, app.depth_to_color{i}, rs_error);
                rs_check_error(app.err);
                [app.scale{i}, app.err] = calllib('realsense', 'rs_get_device_depth_scale', app.dev{i}, rs_error);
                rs_check_error(app.err);
            end
            app.selectdev = 1;
            load('optionstable.mat');
            app.optionTable = optionsTable;
            clear optionsTable;
            %app.camera = 'SR300';
            app.tabSelection = app.SR300Tab; % tab object is to be used as container
            app.SR300ListBox.Items = getOptionsTitles(app); % Items must be cell array
            app.SR300ListBox.Value = app.SR300ListBox.Items(1);
            selectedParameter(app, app.SR300ListBox.Value); % starts with slider generated
            
            %create dropdown for camera selection
            app.CameraDropDown = uidropdown(app.tabSelection);
            app.CameraDropDown.Items = cellstr(categorical(1:app.deviceCount));
            app.CameraDropDown.ValueChangedFcn = createCallbackFcn(app, @CameraDropDownValueChanged, true);
            app.CameraDropDown.Position = [457 287 100 22];
            app.CameraDropDown.Value = '1';
            %app.CameraDropDown.TooltipString = 'Select Camera';
            app.CameraDropDownLabel = uilabel(app.tabSelection);
            app.CameraDropDownLabel.HorizontalAlignment = 'right';
            app.CameraDropDownLabel.Position = [398 318 67 15];
            app.CameraDropDownLabel.Text = 'Camera:';
            
            %initialize stream snapshot variables
            app.countcolor = 0;
            app.countdepth = 0;
            app.countpcl = 0;
            
            %initialize variables for ivcam presets
            app.ivcamopts = [18, 19, 20, 21, 22, 23, 24, 25, 26, 27, 12, 13, 15, 16, 14];
            app.ivcampresets = load('ivcampresets.mat');
            
            %timers for stream display scripts
            app.timercolor = timer('TimerFcn', @(x, y) color(app, x, y), 'ExecutionMode', 'fixedRate', 'Period', 1/30, 'Name', 'timercolor');
            app.timerdepth = timer('TimerFcn', @(x, y) depth(app, x, y), 'ExecutionMode', 'fixedRate', 'Period', 1/30, 'Name', 'timerdepth');
            app.timerpcl = timer('TimerFcn', @(x, y) pcl(app, x, y), 'ExecutionMode', 'fixedRate', 'Period', 1/30, 'Name', 'timerpcl');
            app.timerall = timer('TimerFcn', @(x, y) allcam(app, x, y), 'ExecutionMode', 'fixedRate', 'Period', 1/30, 'Name', 'timerall');
            
            %hide figure 1
            set(figure(1),'Visible','off');
            drawnow;
        end

        % Callback function
        function CameraDropDownValueChanged(app, event)
            %set selected device to selected camera and get options for new camera selection
            app.selectdev = str2num(app.CameraDropDown.Value);
            GetOptions(app);
        end

        % Size changed function: F200Tab
        function F200TabNew(app, event)
            position = app.F200Tab.Position;
            app.tabSelection = app.F200Tab; %changes object to new tab
            app.F200ListBox.Items = getOptionsTitles(app);
            app.F200ListBox.Value = app.F200ListBox.Items(1);
            
            %selectedParameter(app, app.SR300ListBox.Value);%starts with slider generated
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
         %   position = app.UIFigure.Position;
        end

        % Value changed function: allstreamButton
        function allstreamButtonValueChanged(app, event)
            if app.allstreamButton.Value
                %create figure and divide into subaxes for each stream
                app.figall = figure('Name', 'All Cameras');
                for i = 1:2 * app.deviceCount
                    app.axall{i} = subplot(2, app.deviceCount, i);
                end
                start(app.timerall);
            else
                stop(app.timerall);
                close(app.figall);
            end
        end

        % Value changed function: colorstreamButton
        function colorstreamButtonValueChanged(app, event)
            if app.colorstreamButton.Value
                app.figcolor = figure('Name', 'Color');
                app.axcolor = axes;
                start(app.timercolor);
            else
                stop(app.timercolor);
                close(app.figcolor);
            end
        end

        % Value changed function: depthstreamButton
        function depthstreamButtonValueChanged(app, event)
            if app.depthstreamButton.Value
                app.figdepth = figure('Name', 'Depth');
                app.axdepth = axes;
                start(app.timerdepth);
            else
                stop(app.timerdepth);
                close(app.figdepth);
            end
        end

        % Value changed function: ivcampresetDropDown
        function ivcampresetDropDownValueChanged(app, event)
            app.ivcamoptvals = cell2mat(app.ivcampresets.ivcampresets(find(strcmp(app.ivcampresets.ivcampresets, app.ivcampresetDropDown.Value)), 2:16));
            setdeviceoptions(app);
            GetOptions(app);
        end

        % Value changed function: pointcloudButton
        function pointcloudButtonValueChanged(app, event)
            if app.pointcloudButton.Value
                app.figpcl = figure('Name', 'Point Cloud');
                app.axpcl = axes;
                start(app.timerpcl);
            else
                stop(app.timerpcl);
                close(app.figpcl);
            end
        end

        % Value changed function: SR300ListBox
        function selectedParameter(app, event)
            titles = app.optionTable(1:68,2);
            app.index = uint8(0);
            %title = app.F200ListBox.Value;
            indeces = strfind(cellstr(titles),app.SR300ListBox.Value,'ForceCellOutput',true); %test for finding string           
            app.index = find(~cellfun(@isempty,indeces)); %gets index of selection
            stringTabSelection = [cell2mat(app.optionTable(app.index,1)) 'Tab']; % string: 'SR300', 'F200' etc
            app.tabSelection = app.(stringTabSelection); % uses 'SR300','F200' to save tab selection into tab property
            app.functionCall =  cell2mat(app.optionTable(app.index,9)); % 9 corresponds to the name of the... 
                                                                 %realsense function in the 9th column in optionstable.mat
            app.opt = app.rsEnum.rs_option.(app.functionCall);
            buildSelection(app); %builds slider
            clear titles;
        end

        % Button pushed function: 
        % setallparameterstodefaultvaluesButton
        function setallparameterstodefaultvaluesButtonPushed(app, event)
            for i = 1:68
                [~, app.err] = calllib('realsense', 'rs_set_device_option', app.dev{app.selectdev}, i - 1, cell2mat(app.optionTable(i,4)), rs_error);
                rs_check_error(app.err);
            end
            GetOptions(app);
        end

        % Button pushed function: setselectiontodefaultButton
        function setselectiontodefaultButtonPushed(app, event)
            [~, app.err] = calllib('realsense', 'rs_set_device_option', app.dev{app.selectdev}, app.opt, app.minMaxDefaultStepValues(3), rs_error);
            rs_check_error(app.err);
            GetOptions(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SR300Tab = uitab(app.TabGroup);
            app.SR300Tab.Title = 'SR300';
            app.SR300ListBox = uilistbox(app.SR300Tab);
            app.SR300ListBox.Items = {};
            app.SR300ListBox.ValueChangedFcn = createCallbackFcn(app, @selectedParameter, true);
            app.SR300ListBox.Value = {};
            app.setselectiontodefaultButton = uibutton(app.SR300Tab, 'push');
            app.setselectiontodefaultButton.ButtonPushedFcn = createCallbackFcn(app, @setselectiontodefaultButtonPushed, true);
            app.setselectiontodefaultButton.Text = 'set selection to default';
            app.setallparameterstodefaultvaluesButton = uibutton(app.SR300Tab, 'push');
            app.setallparameterstodefaultvaluesButton.ButtonPushedFcn = createCallbackFcn(app, @setallparameterstodefaultvaluesButtonPushed, true);
            app.setallparameterstodefaultvaluesButton.Text = 'set all parameters to default values';
            app.allstreamButton = uibutton(app.SR300Tab, 'state');
            app.allstreamButton.ValueChangedFcn = createCallbackFcn(app, @allstreamButtonValueChanged, true);
            app.allstreamButton.Text = 'all stream';
            app.colorstreamButton = uibutton(app.SR300Tab, 'state');
            app.colorstreamButton.ValueChangedFcn = createCallbackFcn(app, @colorstreamButtonValueChanged, true);
            app.colorstreamButton.Text = 'color stream';
            app.depthstreamButton = uibutton(app.SR300Tab, 'state');
            app.depthstreamButton.ValueChangedFcn = createCallbackFcn(app, @depthstreamButtonValueChanged, true);
            app.depthstreamButton.Text = 'depth stream';
            app.pointcloudButton = uibutton(app.SR300Tab, 'state');
            app.pointcloudButton.ValueChangedFcn = createCallbackFcn(app, @pointcloudButtonValueChanged, true);
            app.pointcloudButton.Text = 'point cloud';
            app.ivcampresetDropDownLabel = uilabel(app.SR300Tab);
            app.ivcampresetDropDownLabel.Text = 'ivcam preset';
            app.ivcampresetDropDown = uidropdown(app.SR300Tab);
            app.ivcampresetDropDown.Items = {'ShortRange', 'LongRange', 'BackgroundSegmentation', 'GestureRecognition', 'ObjectScanning', 'FaceAnalytics', 'FaceLogin', 'GRCursor', 'Default', 'MidRange', 'IROnly'};
            app.ivcampresetDropDown.ValueChangedFcn = createCallbackFcn(app, @ivcampresetDropDownValueChanged, true);
            app.ivcampresetDropDown.Value = 'ShortRange';
            app.F200Tab = uitab(app.TabGroup);
            app.F200Tab.SizeChangedFcn = createCallbackFcn(app, @F200TabNew, true);
            app.F200Tab.Title = 'F200';
            app.F200ListBoxLabel = uilabel(app.F200Tab);
            app.F200ListBoxLabel.Text = 'F200';
            app.F200ListBox = uilistbox(app.F200Tab);
            app.F200ListBox.Items = {};
            app.F200ListBox.Value = {};
            app.R200Tab = uitab(app.TabGroup);
            app.R200Tab.Title = 'R200';
            app.FisheyeTab = uitab(app.TabGroup);
            app.FisheyeTab.Title = 'Fisheye';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SR300_App_vAlpha

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end