classdef tstWithSlider_v4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        SR300Tab                 matlab.ui.container.Tab
        SR300COLORListBoxLabel   matlab.ui.control.Label
        SR300COLORListBox        matlab.ui.control.ListBox
        PointCloudStreamButton   matlab.ui.control.Button
        ColorStreamButton        matlab.ui.control.Button
        DepthStreamButton        matlab.ui.control.Button
        SettoDefaultValueButton  matlab.ui.control.Button
        SetAllDefaultButton      matlab.ui.control.Button
        StopStreamButton         matlab.ui.control.Button
        F200Tab                  matlab.ui.container.Tab
        F200ListBoxLabel         matlab.ui.control.Label
        F200ListBox              matlab.ui.control.ListBox
        R200Tab                  matlab.ui.container.Tab
        FisheyeTab               matlab.ui.container.Tab
    end

%This program shows options for paramters with the SR300
%requires optionstable.mat
    properties (Access = public)
        %slider creation
        SliderMinMaxText           matlab.ui.control.Label
        SliderMinValue             matlab.ui.control.Label
        SliderMaxValue             matlab.ui.control.Label
        SliderText                 matlab.ui.control.Label
        SliderLabel                matlab.ui.control.Label
        SliderITSELF               matlab.ui.control.Slider
        ValueSpinnerLabel          matlab.ui.control.Label
        ValueSpinner               matlab.ui.control.Spinner
        %passed to program for modifying paramters
        newParameterValue
        %titles, descriptions, and parameters
        optionsTitlesCamera
        %num array for defaul, min, and max values
        minMaxDefaultStepValues
        %increases increment/decrement step to 10
        whiteBalanceIndex
        %name of the camera
        camera

        index
        
        functionCall %for saving the paramter/function's name
        
                %C++ library variables/objects        
        dev %pointer - camera
        opt %from 0 to 68 (corresponds to functions' names
        dev_opt_current_value %t
        rsoptions
        width
        height
        fps
        ctx
        rsEnum
        err
        rsoptionscount
        depth_intrin
        color_intrin
        depth_to_color
        methodinfo
        structs
        ThunkLibName
        scale
        ardobj
        %%for stop buttons for streams
        dogenerate
        docolor
        dodepth
%         axis1
%         axis2
%         
    end

    methods (Access = private)
%>>>>>>>>> GetOptions >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        function GetOptions(app)
            %
            [app.dev_opt_current_value, ~, app.err] = calllib('realsense', 'rs_get_device_option', app.dev, app.opt, rs_error);
            rs_check_error(app.err);
            app.ValueSpinner.Value = app.dev_opt_current_value;
            app.SliderITSELF.Value = app.ValueSpinner.Value;
            

         end
%>>>>>>>>>> END of GetOptioons >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>

%>>>>>>>>>> ResetAllOptions >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
%         function ResetAllOptions(app)
%             for i = 1:app.rsoptionscount
%                 app.opt = app.rsoptions{i};
%                 [~, app.err] = calllib('realsense', 'rs_reset_device_options_to_default', app.dev, app.opt, 1, rs_error);
%                 rs_check_error(app.err);
%             end            
%             
%         end
%>>>>>>>>>> END of ResetAllOptions >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
       
%>>>>>>>>>>  buildSelection >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        % app required parameter
        % minMax is a 2 element horizontal integers vector 
                % 1st element = min 
                % 2nd = MAX values for parameter
        % description is a cell-array with comments about the parameter
            %the cell-array has syntax {'blah blah blah';'blah blah blah'} - the ; (semicolon) for newline
        % discreteOrNot can be any number  for discrete slider or 0 for nondiscrete
        % stepsDisplayed is a function that generates a vector with up to 10 elements
        % WORKS FOR PARAMATERS WITH STEP OF 1 (positive integer)
%        function buildSelection(app, title, index)
    function buildSelection(app, index)
            eraseSlider(app); % clears previous slider and number boxes
            if index == 10 %controls steps for whiteBalance
                 app.whiteBalanceIndex=1;
             else
                 app.whiteBalanceIndex = 0;                  
             end

            %app.whiteBalanceIndex = 0;                  
            load('optionstable.mat');
            %creates array of doubles min, max, default, and step parameter values from cell array
            %o
            app.minMaxDefaultStepValues = cell2mat([optionsTable(index,5);optionsTable(index,6);optionsTable(index,4);optionsTable(index,7)]);
            
            %optionsTable(index,)
            
            stepsDisplayed = getValuesDispBySlider(app,app.minMaxDefaultStepValues);
        
          % Create SliderMinMaxText
            app.SliderMinMaxText = uilabel(app.UIFigure);
            app.SliderMinMaxText.Position = [428 336 28 42];
            app.SliderMinMaxText.Text = {'max'; ''; 'min'};

            % Create SliderMinValue - box at the slider's left-hand side             
            app.SliderMinValue = uilabel(app.UIFigure);
            app.SliderMinValue.BackgroundColor = [0.8588 0.9451 0.9882];
            app.SliderMinValue.HorizontalAlignment = 'right';
            app.SliderMinValue.FontWeight = 'bold';
            app.SliderMinValue.Position = [457 336 42 15];
            app.SliderMinValue.Text = num2str(app.minMaxDefaultStepValues(1));
            %app.SliderMinValue.Text = num2str(minMax(1)) 

            % Create SliderMaxValue - box at the slider's left-hand side
            app.SliderMaxValue = uilabel(app.UIFigure);
            app.SliderMaxValue.BackgroundColor = [0.8588 0.9451 0.9882];
            app.SliderMaxValue.HorizontalAlignment = 'right';
            app.SliderMaxValue.FontWeight = 'bold';
            app.SliderMaxValue.Position = [457 363 42 15];
            app.SliderMaxValue.Text = num2str(app.minMaxDefaultStepValues(2));

            % Create Slider Description
            app.SliderText = uilabel(app.UIFigure);
            app.SliderText.Position = [340 20 250 60];
            app.SliderText.Text = char(cell2mat(optionsTable(index,3)));

            % Create SliderLabel
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.HorizontalAlignment = 'right';
            app.SliderLabel.FontWeight = 'bold';
            app.SliderLabel.Position = [335 433 181 15];
            app.SliderLabel.Text = char(cell2mat(optionsTable(index,2)));

            % Create SliderITSELF
            app.SliderITSELF = uislider(app.UIFigure);
            app.SliderITSELF.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.SliderITSELF.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.SliderITSELF.Limits = [app.minMaxDefaultStepValues(1) app.minMaxDefaultStepValues(2)];
            app.SliderITSELF.Value = app.minMaxDefaultStepValues(3); %app.minMaxDefaultStepValues(3);
            app.SliderITSELF.MajorTicks = stepsDisplayed;
            app.SliderITSELF.Orientation = 'vertical';
            app.SliderITSELF.MinorTicks = 1;
            app.SliderITSELF.Position = [600.6875 42 3 406];
            
            % Create ValueSpinnerLabel
            app.ValueSpinnerLabel = uilabel(app.UIFigure);
            app.ValueSpinnerLabel.HorizontalAlignment = 'right';
            app.ValueSpinnerLabel.Position = [382.375 303 36 15];
            app.ValueSpinnerLabel.Text = 'Value';

            % Create ValueSpinner
            app.ValueSpinner = uispinner(app.UIFigure);
            app.ValueSpinner.Limits = [app.minMaxDefaultStepValues(1) app.minMaxDefaultStepValues(2)];
            app.ValueSpinner.Value = app.SliderITSELF.Value;
            app.ValueSpinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.ValueSpinner.ValueChangingFcn = createCallbackFcn(app, @SpinnerValueChanging, true);
            app.ValueSpinner.Position = [420.375 299 100 22];
            GetOptions(app);
            
            clear optionsTable;
        end
        
        function eraseSlider(app) %clears windodow from previous selection
            delete(app.SliderMinValue);
            delete(app.SliderMinMaxText);
            delete(app.SliderMaxValue);
            delete(app.SliderText);
            delete(app.SliderLabel);
            delete(app.SliderITSELF);
            delete(app.ValueSpinnerLabel);
            delete(app.ValueSpinner);
            clear app.whiteBalanceIndex;
            clear app.minMaxDefaultStepValues;
        end
%>>>>>>>>>>  END of buildSelection

%>>>>>>>>>>  stepsDisplayed >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
%calcultes which and how many steps the slider has
function stepsDisplayed = getValuesDispBySlider(app,minMaxDefaultStep)
            %if (minMaxDefaultStep(1) < 0 && minMaxDefaultStep(2)>10)  % decides steps in slider
            if minMaxDefaultStep(2)>10  % decides steps in slider
                step = (minMaxDefaultStep(2)-minMaxDefaultStep(1))/10;
                stepsDisplayed = zeros(1,11);
                stepsDisplayed(1) = minMaxDefaultStep(1); %initializes smallest value
                stepsDisplayed(11) = minMaxDefaultStep(2); %initializes smallest value
                for i = 2:10 %initializes slider min < (slider values) < max 
                   stepsDisplayed(i) = round(step + stepsDisplayed(i-1));
                end
                stepsDisplayed(6)= app.dev_opt_current_value
                %minMaxDefaultStep(3);%center of slide= default value
                
            %elseif ( minMaxDefaultStep(1) == 0 && minMaxDefaultStep(2) < 11 ) %requires one more element in the display (0)
            elseif minMaxDefaultStep(2)  == 0 
                stepsDisplayed = zeros(1,minMaxDefaultStep(2)+1);%+1 to make sure the max value will be shown in arrays with 0 values
                stepsDisplayed(1) = app.minMaxDefaultStepValues(1);
                stepsDisplayed(minMaxDefaultStep(2)+1) = minMaxDefaultStep(2);
                for i = 2:int8(minMaxDefaultStep(2)) %initializes slider min < (slider values) < max 
                   stepsDisplayed(i) = stepsDisplayed(i-1)+1;
                end
            else
                stepsDisplayed = zeros(1,minMaxDefaultStep(2)+1);%+1 to make sure the max value will be shown in arrays with 0 values
                stepsDisplayed(1) = app.minMaxDefaultStepValues(1);
                stepsDisplayed(minMaxDefaultStep(2)+1) = minMaxDefaultStep(2);
                for i = 2:int8(minMaxDefaultStep(2)) %initializes slider min < (slider values) < max 
                   stepsDisplayed(i) = stepsDisplayed(i-1)+1;
                end
            end
%             else
%                 stepsDisplayed = zeros(1,minMaxDefaultStep(2));%+1 to make sure the max value will be shown in arrays with 0 values
%                 stepsDisplayed(1) = minMaxDefaultStep(1);
%                 stepsDisplayed(int16(minMaxDefaultStep(2)))=minMaxDefaultStep(2)
%                 for i = 2:int16(minMaxDefaultStep(2)-1) %initializes slider min < (slider values) < max 
%                    stepsDisplayed(i) = stepsDisplayed(i-1)+1;
%                 end
%                 if (rem(size(stepsDisplayed),2) == 0) %if max number is even
%                     stepsDisplayed(((size(stepsDisplayed))/2)+1)=0;
%                 else
%                     stepsDisplayed(round((size(stepsDisplayed))/2)+1)=0;
%                 end
          
        end
%>>>>>>>>>>  END of getValuesDispBySlider

%>>>>>>>>>>  SliderValueChanged >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        %whiteBalanceIndex: if ~= 0, step cahnges from 1 to 10 (only parameter with steps of 1)
        function SliderValueChanged(app,event) %updates spinner field
            if app.whiteBalanceIndex == 0
                app.ValueSpinner.Value = round(app.SliderITSELF.Value); %rounded to the nearest integer
                app.newParameterValue = round(app.SliderITSELF.Value);
            else
                app.ValueSpinner.Value = round(10*app.SliderITSELF.Value); %rounded to the nearest integer
                app.newParameterValue = round(10*app.SliderITSELF.Value);
            end
            app.SliderITSELF.Value = app.ValueSpinner.Value;  
            app.dev_opt_current_value = app.SliderITSELF.Value;
            [~, app.err] = calllib('realsense', 'rs_set_device_option', app.dev, app.opt, app.dev_opt_current_value, rs_error);
            rs_check_error(app.err);
            GetOptions(app);
        end
%>>>>>>>>>>  END of SliderValueChanged

%>>>>>>>>>>  SliderValueChanging >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        %whiteBalanceIndex: if ~= 0, step cahnges from 1 to 10 (only parameter with steps of 1)        
        function SliderValueChanging(app,event) %updates spinner field
            if app.whiteBalanceIndex == 0
                app.ValueSpinner.Value = round(event.Value);
               % app.newParameterValue = round(event.Value);
            else
                app.ValueSpinner.Value = round(10*event.Value);
                %app.newParameterValue = round(10*event.Value);
            end
        end
%>>>>>>>>>>  END of SliderValueChanging

%>>>>>>>>>>  SpinnerValueChanged >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        %whiteBalanceIndex: if ~= 0, step cahnges from 1 to 10 (only parameter with steps of 1)
        function SpinnerValueChanged(app,event) % updates slider with values from spinner
                app.SliderITSELF.Value = app.ValueSpinner.Value;
                app.dev_opt_current_value = app.SliderITSELF.Value;
                [~, app.err] = calllib('realsense', 'rs_set_device_option', app.dev, app.opt, app.dev_opt_current_value, rs_error);
                rs_check_error(app.err);
                GetOptions(app);
                %app.newParameterValue = app.ValueSpinner.Value;
        end
%>>>>>>>>>>  END of SpinnerValueChanged

%>>>>>>>>>>  SpinnerValueChanging >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        %whiteBalanceIndex: if ~= 0, step cahnges from 1 to 10 (only parameter with steps of 1)
        function SpinnerValueChanging(app,event) % updates slider with values from spinner
            app.SliderITSELF.Value = event.Value;
            app.newParameterValue = event.Value;
        end
%>>>>>>>>>>  END of stepsDisplayed

%>>>>>>>>>> optionTitles >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        %gets a list of paramater titles
        %parameters 
            % cam = 'SR300','R200',F200','ALL' (ALL for options that all cameras can use
        function optionTitleReturned = getOptionsTitles(app) %gets titles from mat file with 69x7 cell array
            load('optionstable.mat');
            
            %optionstable.mat is a 69x7 cell array with specs
                % optionsTable(1-69,2) - cameras
                % optionsTable(1-69,2) - titles
                % optionsTable(1-69,3) - descriptions - must be passed as cell arrayswith semicolon for new paragraph
                % optionsTable(1-69,4) - minMaxDefaultValue(1) = default value
                % optionsTable(1-69,5) - minMaxDefaultValue(2)  = minValue
                % optionsTable(1-69,6) - minMaxDefaultValue(3) = maxValue
                % optionsTable(1-69,7) - step size - white balance has large value; all others are one
                % optionsTable(1-69,8) - placeHolder8
                % GENERAL: optionsTable(1-12,i), i = 1..7
                % 	 optionsTable(66-68,i), i = 1..7
                % F200:  optionsTable(13-18,i), i = 1..7F200
                % SR300 AUTO RANGE OPTIONS:  optionsTable(19-28,i), i = 1..7
                % 
                % R200 AUTO RANGE OPTIONS:  optionsTable(29-56,i), i = 1..7 %NO VALUES AVAILABLE
                % FISHEYE:   optionsTable(57-65,i), i = 1..7 %NO VALUES AVAILABLE
                % 	 optionsTable(69,i), i = 1..7 NO VALUES AVAILABLE FOR OPTION 69
            switch app.camera
                case 'SR300'
                        optionTitleReturned = [optionsTable(1:12,2);optionsTable(19:28,2)];
                        app.optionsTitlesCamera = char(optionTitleReturned)
                 case 'F200'
                         optionTitleReturned = optionsTable(12:18,2);
                         app.optionsTitlesCamera = char(optionTitleReturned);
%                 case 'R200'
%                         optionTitleReturned = optionsTable(29:56,2);
%                         app.optionsTitlesCamera = char(optionTitleReturned);
%                 case 'Fisheye'
%                         optionTitleReturned = optionsTable(57:65,2);
%                         app.optionsTitlesCamera = char(optionTitleReturned);

            end
            clear optionsTable;
        end % END of function optionTitleSR300
%>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>> 

%>>>>>>>>>>  getDescription >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        %breaks one line of description into two or more lines
        %description is a cell array, column 3 of optionsTable
%         function  descriptionCellArray = getDescription(app,description)
%             load('optionstable.mat');
%             charArrDescript = length(cell2mat(optionsTable(index,3)))
%             for i = 1:
%                 while 
%                 descriptionCellArray = 
%             end
%             clear optionsTable;
%         end 


    end % END of PUBLIC methods


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% PROGRAM STARTUP  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.opt = 0; %starts with Contrast
            
            app.width = 640; 
            app.height = 480;
            app.fps = 30;
            %app.ardobj = arduino;
            %app.axis1 = servo(app.ardobj,'D2');
            %app.axis2 = servo(app.ardobj,'D3');
            loadlibrary('realsense', 'rsutil.h', ...
                        'addheader','rs.h', ...
                        'mfilename', 'realsense');
            [app.methodinfo, app.structs, app.rsEnum, app.ThunkLibName] = realsense;
            [app.ctx, app.err] = calllib('realsense', 'rs_create_context', 11201, rs_error);
            rs_check_error(app.err);
            [app.dev, app.err] = calllib('realsense', 'rs_get_device', app.ctx, 0, rs_error);
            rs_check_error(app.err);
            [~, app.err] = calllib('realsense', 'rs_enable_stream', app.dev, app.rsEnum.rs_stream.RS_STREAM_COLOR, ...
                app.width, app.height, app.rsEnum.rs_format.RS_FORMAT_RGB8, app.fps, rs_error);
            rs_check_error(app.err);
            [~, app.err] = calllib('realsense', 'rs_enable_stream', app.dev, app.rsEnum.rs_stream.RS_STREAM_DEPTH, ...
                app.width, app.height, app.rsEnum.rs_format.RS_FORMAT_Z16, app.fps, rs_error);
            rs_check_error(app.err);
            [~, app.err] = calllib('realsense', 'rs_start_device', app.dev, rs_error);
            rs_check_error(app.err);
            app.depth_intrin = libstruct('rs_intrinsics', struct());
            [~, app.depth_intrin, app.err] = calllib('realsense', 'rs_get_stream_intrinsics', ...
                app.dev, app.rsEnum.rs_stream.RS_STREAM_DEPTH, app.depth_intrin, rs_error);
            rs_check_error(app.err);
            app.color_intrin = libstruct('rs_intrinsics', struct());
            [~, app.color_intrin, app.err] = calllib('realsense', 'rs_get_stream_intrinsics', ...
                app.dev, app.rsEnum.rs_stream.RS_STREAM_COLOR, app.color_intrin, rs_error);
            rs_check_error(app.err);
            app.depth_to_color = libstruct('rs_extrinsics', struct());
            [~, app.depth_to_color, app.err] = calllib('realsense', 'rs_get_device_extrinsics', ...
                app.dev, app.rsEnum.rs_stream.RS_STREAM_DEPTH, app.rsEnum.rs_stream.RS_STREAM_COLOR, app.depth_to_color, rs_error);
            rs_check_error(app.err);
            [app.scale, app.err] = calllib('realsense', 'rs_get_device_depth_scale', app.dev, rs_error);
            rs_check_error(app.err);
            
            app.camera = 'SR300';
            app.SR300COLORListBox.Items = getOptionsTitles(app);%itesm must be cell array
            app.SR300COLORListBox.Value = app.SR300COLORListBox.Items(1);
            selectedParameter(app, app.SR300COLORListBox.Value);%starts with slider generated
           
     
        end

        % Button pushed function: ColorStreamButton
        function ColorStreamButtonPushed(app, event)
            app.docolor = 1;
            color
        end

        % Button pushed function: DepthStreamButton
        function DepthStreamButtonPushed(app, event)
            app.dodepth = 1;
            depth
        end

        % Size changed function: F200Tab
        function F200TabNew(app, event)
            position = app.F200Tab.Position;
            app.camera = 'F200';
            app.F200ListBox.Items = getOptionsTitles(app);
            app.F200ListBox.Value = app.F200ListBox.Items(1);
            
            %selectedParameter(app, app.SR300COLORListBox.Value);%starts with slider generated
        end

        % Button pushed function: PointCloudStreamButton
        function PointCloudStreamButtonPushed(app, event)
            app.dogenerate = 1;
            generate
        end

        % Button pushed function: SetAllDefaultButton
        function SetAllDefaultButtonPushed(app, event)
            load('optionstable.mat');
            for i = 1:68
                [~, app.err] = calllib('realsense', 'rs_set_device_option', app.dev, i - 1, cell2mat(optionsTable(i,4)), rs_error);
                rs_check_error(app.err);
            end
            GetOptions(app);
            clear optionsTable;
        end

        % Button pushed function: SettoDefaultValueButton
        function SettoDefaultValueButtonPushed(app, event)
            [~, app.err] = calllib('realsense', 'rs_set_device_option', app.dev, app.opt, app.minMaxDefaultStepValues(3), rs_error);
            rs_check_error(app.err);
            GetOptions(app);
        end

        % Button pushed function: StopStreamButton
        function StopStreamButtonPushed(app, event)
            app.dogenerate = 0;
            app.docolor = 0;
            app.dodepth = 0;
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
         %   position = app.UIFigure.Position;
        end

        % Value changed function: SR300COLORListBox
        function selectedParameter(app, event)
            load('optionstable.mat');
            titles = optionsTable(1:68,2);
            app.index = uint8(0);
            %title = app.F200ListBox.Value;
            indeces = strfind(cellstr(titles),app.SR300COLORListBox.Value,'ForceCellOutput',true) %test for finding string           
            app.index = find(~cellfun(@isempty,indeces)) %gets index of selection
            app.functionCall =  cell2mat(optionsTable(app.index,9)); % 9 corresponds to the name of the... 
                                                                 %realsense function in the 9th column in optionstable.mat
            app.opt = app.rsEnum.rs_option.(app.functionCall);
            buildSelection(app,app.index); %builds slider
            
            clear optionsTable;
            clear titles;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SR300Tab = uitab(app.TabGroup);
            app.SR300Tab.Title = 'SR300';
            app.SR300COLORListBoxLabel = uilabel(app.SR300Tab);
            app.SR300COLORListBoxLabel.Text = 'SR300 - COLOR';
            app.SR300COLORListBox = uilistbox(app.SR300Tab);
            app.SR300COLORListBox.Items = {};
            app.SR300COLORListBox.ValueChangedFcn = createCallbackFcn(app, @selectedParameter, true);
            app.SR300COLORListBox.Value = {};
            app.PointCloudStreamButton = uibutton(app.SR300Tab, 'push');
            app.PointCloudStreamButton.ButtonPushedFcn = createCallbackFcn(app, @PointCloudStreamButtonPushed, true);
            app.PointCloudStreamButton.Text = 'Point Cloud Stream';
            app.ColorStreamButton = uibutton(app.SR300Tab, 'push');
            app.ColorStreamButton.ButtonPushedFcn = createCallbackFcn(app, @ColorStreamButtonPushed, true);
            app.ColorStreamButton.Text = 'Color Stream';
            app.DepthStreamButton = uibutton(app.SR300Tab, 'push');
            app.DepthStreamButton.ButtonPushedFcn = createCallbackFcn(app, @DepthStreamButtonPushed, true);
            app.DepthStreamButton.Text = 'Depth Stream';
            app.SettoDefaultValueButton = uibutton(app.SR300Tab, 'push');
            app.SettoDefaultValueButton.ButtonPushedFcn = createCallbackFcn(app, @SettoDefaultValueButtonPushed, true);
            app.SettoDefaultValueButton.Text = 'Set to Default Value';
            app.SetAllDefaultButton = uibutton(app.SR300Tab, 'push');
            app.SetAllDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @SetAllDefaultButtonPushed, true);
            app.SetAllDefaultButton.Text = 'Set All Default';
            app.StopStreamButton = uibutton(app.SR300Tab, 'push');
            app.StopStreamButton.ButtonPushedFcn = createCallbackFcn(app, @StopStreamButtonPushed, true);
            app.StopStreamButton.Text = 'Stop Stream';
            app.F200Tab = uitab(app.TabGroup);
            app.F200Tab.SizeChangedFcn = createCallbackFcn(app, @F200TabNew, true);
            app.F200Tab.Title = 'F200';
            app.F200ListBoxLabel = uilabel(app.F200Tab);
            app.F200ListBoxLabel.Text = 'F200';
            app.F200ListBox = uilistbox(app.F200Tab);
            app.F200ListBox.Items = {};
            app.F200ListBox.Value = {};
            app.R200Tab = uitab(app.TabGroup);
            app.R200Tab.Title = 'R200';
            app.FisheyeTab = uitab(app.TabGroup);
            app.FisheyeTab.Title = 'Fisheye';
        end
    end

    methods (Access = public)

        % Construct app
        function app = tstWithSlider_v4

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end